classdef matlab_project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AlgorithmProjectUIFigure       matlab.ui.Figure
        TabGroup2                      matlab.ui.container.TabGroup
        LoginTab                       matlab.ui.container.Tab
        Image15                        matlab.ui.control.Image
        LoginPanel                     matlab.ui.container.Panel
        SubmitButton                   matlab.ui.control.Button
        PasswordEditField              matlab.ui.control.EditField
        PasswordEditFieldLabel         matlab.ui.control.Label
        UserNameEditField              matlab.ui.control.EditField
        UserNameEditFieldLabel         matlab.ui.control.Label
        LinkedIn                       matlab.ui.control.Image
        Instagram                      matlab.ui.control.Image
        FeedBack                       matlab.ui.control.Image
        Youtube                        matlab.ui.control.Image
        GifImage                       matlab.ui.control.Image
        IkcLogo                        matlab.ui.control.Image
        Backgorund                     matlab.ui.control.Image
        AppTab                         matlab.ui.container.Tab
        TabGroup                       matlab.ui.container.TabGroup
        SortAlgorithmsTab              matlab.ui.container.Tab
        WriteLabel                     matlab.ui.control.Label
        ReadLabel                      matlab.ui.control.Label
        LogOut                         matlab.ui.control.Image
        SortingInfo                    matlab.ui.control.Image
        TIMECOMPARISONButton           matlab.ui.control.Button
        PleasesetyourmanuelarrayLabel  matlab.ui.control.Label
        CREATEARRAYButton_2            matlab.ui.control.Button
        EditField_11                   matlab.ui.control.EditField
        Lamp                           matlab.ui.control.Lamp
        Image5                         matlab.ui.control.Image
        Image4                         matlab.ui.control.Image
        Switch                         matlab.ui.control.ToggleSwitch
        SKIPButton                     matlab.ui.control.Button
        CLEARButton_2                  matlab.ui.control.Button
        STOPButton                     matlab.ui.control.Button
        ARRAYSIZELabel                 matlab.ui.control.Label
        upper_bound                    matlab.ui.control.NumericEditField
        UPPERBOUNDLabel                matlab.ui.control.Label
        LOWERBOUNDLabel                matlab.ui.control.Label
        SEEDCheckBox                   matlab.ui.control.CheckBox
        SELECTIONSORTButton            matlab.ui.control.Button
        SHELLSORTButton                matlab.ui.control.Button
        DEFAULTDATAButton              matlab.ui.control.Button
        SPEEDSlider                    matlab.ui.control.Slider
        SPEEDSliderLabel               matlab.ui.control.Label
        RANDOMARRAYButton              matlab.ui.control.Button
        BUCKETSORTButton               matlab.ui.control.Button
        COUNTINGSORTButton             matlab.ui.control.Button
        RADIXSORTButton                matlab.ui.control.Button
        HEAPSORTButton                 matlab.ui.control.Button
        QUICKSORTButton                matlab.ui.control.Button
        MERGESORTButton                matlab.ui.control.Button
        BUBBLESORTButton               matlab.ui.control.Button
        INSERTIONSORTButton            matlab.ui.control.Button
        lower_bound                    matlab.ui.control.NumericEditField
        array_size                     matlab.ui.control.NumericEditField
        UIAxes2                        matlab.ui.control.UIAxes
        MatrixOperationsTab            matlab.ui.container.Tab
        LogOut1                        matlab.ui.control.Image
        Image14                        matlab.ui.control.Image
        Image13                        matlab.ui.control.Image
        SecondMatrixRowEditField       matlab.ui.control.NumericEditField
        SecondMatrixRowEditFieldLabel  matlab.ui.control.Label
        Image12_3                      matlab.ui.control.Image
        Image12_2                      matlab.ui.control.Image
        Image12                        matlab.ui.control.Image
        EnterMatrixByHandButton        matlab.ui.control.Button
        MatrixInfo                     matlab.ui.control.Image
        col2                           matlab.ui.control.NumericEditField
        SecondMatrixColumnLabel        matlab.ui.control.Label
        col1androw2                    matlab.ui.control.NumericEditField
        FirstMatrixColumnLabel         matlab.ui.control.Label
        row                            matlab.ui.control.NumericEditField
        FirstMatrixRowLabel            matlab.ui.control.Label
        MatrixOperationsLabel          matlab.ui.control.Label
        RESULTMATRIXLabel              matlab.ui.control.Label
        CreateButton                   matlab.ui.control.Button
        SECONDMATRIXLabel              matlab.ui.control.Label
        FIRSTMATRIXLabel               matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        ClearButton                    matlab.ui.control.Button
        UITable3                       matlab.ui.control.Table
        UITable2                       matlab.ui.control.Table
        UITable                        matlab.ui.control.Table
        FibonacciTab                   matlab.ui.container.Tab
        AnimationButton                matlab.ui.control.Button
        LogOut2                        matlab.ui.control.Image
        fibonacci_answer               matlab.ui.control.EditField
        FibonacciInfo                  matlab.ui.control.Image
        FIBONACCILabel                 matlab.ui.control.Label
        ClearButton_4                  matlab.ui.control.Button
        SeethefibonacciratioButton     matlab.ui.control.Button
        FibonacciSeriesLabel           matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        NthnumberofFibonacciLabel      matlab.ui.control.Label
        CALCULATEButton                matlab.ui.control.Button
        EditField                      matlab.ui.control.NumericEditField
        UIAxes7                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        BinarySearchTab                matlab.ui.container.Tab
        LogOut3                        matlab.ui.control.Image
        BinaryInfo                     matlab.ui.control.Image
        DEFAULTARRAYButton             matlab.ui.control.Button
        LOWERBOUNDLabel_2              matlab.ui.control.Label
        UPPERBOUNDLabel_2              matlab.ui.control.Label
        ARRAYSIZELabel_2               matlab.ui.control.Label
        Array_size_14                  matlab.ui.control.NumericEditField
        Upper_Bound_13                 matlab.ui.control.NumericEditField
        Lower_Bound_12                 matlab.ui.control.NumericEditField
        BINARYSEARCHLabel_2            matlab.ui.control.Label
        CLEARButton                    matlab.ui.control.Button
        BINARYSEARCHLabel              matlab.ui.control.Label
        SortedArrayLabel               matlab.ui.control.Label
        UnsortedArrayLabel             matlab.ui.control.Label
        TextArea_3                     matlab.ui.control.TextArea
        TextArea_2                     matlab.ui.control.TextArea
        EditField_3                    matlab.ui.control.NumericEditField
        Spinner                        matlab.ui.control.Spinner
        SEARCHNUMBERINDEXButton        matlab.ui.control.Button
        RANDOMARRAYButton_2            matlab.ui.control.Button
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        RandomizedSelectTab            matlab.ui.container.Tab
        LogOut4                        matlab.ui.control.Image
        RandomizedInfo                 matlab.ui.control.Image
        IdonotwantseesamenumbersCheckBox  matlab.ui.control.CheckBox
        DefaultArrayButton             matlab.ui.control.Button
        LOWERBOUNDLabel_3              matlab.ui.control.Label
        UPPERBOUNDLabel_3              matlab.ui.control.Label
        ARRAYSIZELabel_3               matlab.ui.control.Label
        array_size_16                  matlab.ui.control.NumericEditField
        SORTEDARRAYLabel               matlab.ui.control.Label
        TextArea_6                     matlab.ui.control.TextArea
        RANDOMIZEDSELECTLabel          matlab.ui.control.Label
        NUMBERLabel                    matlab.ui.control.Label
        INDEXLabel                     matlab.ui.control.Label
        UNSORTEDARRAYLabel             matlab.ui.control.Label
        ClearButton_2                  matlab.ui.control.Button
        EditField_8                    matlab.ui.control.NumericEditField
        lower_bound_7                  matlab.ui.control.NumericEditField
        upper_bound_6                  matlab.ui.control.NumericEditField
        TextArea_5                     matlab.ui.control.TextArea
        ithsmallestButton              matlab.ui.control.Button
        RandomArrayButton              matlab.ui.control.Button
        TextArea_4                     matlab.ui.control.TextArea
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes6                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        changingValue_arraysize;
        changingValue_arrayrange;
        unsorted_array;
        changingValueRange;
        changingValueSize;
        changingValue
        CallingApp
        BUTON 
    end
    
    %%Murat KILCI
    %%190403023
    %%%28.11.2022
    methods (Access = private)
        
         %%%% BINARY SEARCH %%%%%%%%%%%%%%%
         function results = binarySearch(app,array,number,index)
            midpoint = ceil(length(array)/2); %%divide the array in half
            if array(midpoint) == number %%If the number we are looking for is in the middle, print it.
                results = midpoint + index;
            elseif array(midpoint) < number %%if the number we are looking for is greater in the midpoint:
                list = array(midpoint+1:length(array)); %%look to the right
                results = binarySearch(app,list,number,midpoint+index);
            else %%if the number we are looking for is small in the midpoint:
                list = array(1:midpoint-1); %%look to the left
                results = binarySearch(app,list,number,index);
            end
        end   
        
        %%%%%%% BINARY SEARCH END %%%%%%%%%%%
        
        %%%%%% INSERTION SORT %%%%%%%%%%%%
        function array = Insertion_sort(app,array) %%insertion sort algorithm function
            x = (1:length(array)); %%x-axis 
            for j = 2:length(array) %%array length
                key = array(j); %%set a key value.
                i = j - 1; %%run through the list of items.
                while i >0 && array(i)> key %%find the correct position.compare the smaller and greater of the numbers. 
                    array(i+1) = array(i); %%Do this only if key is smaller than other values, shift the value one position to the left
                    i = i - 1; %%reposition from right to left.
                    bar(app.UIAxes2,x,array,"black"); %%create bar chart.
                    hold(app.UIAxes2,"on")
                    bar(app.UIAxes2,x(j),array(j),"blue") 
                    bar(app.UIAxes2,x(i+1),array(i+1),"magenta")
                    hold(app.UIAxes2,"off")
                    pause((10-floor(app.SPEEDSlider.Value))*0.01); %%speed button for bar animation

                end
                array(i+1) = key;  %%when finish shifting the number items, our key is sorted correctly.
                
            end
            bar(app.UIAxes2,array,'FaceColor',"[0.4940 0.1840 0.5560]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
            title(app.UIAxes2,'Insertion Sort');
            for i=1:numel(array)
                      text(app.UIAxes2,(i),array(i),num2str(array(i),'%0.0f'),...
                   'HorizontalAlignment','center',...
                   'VerticalAlignment','bottom','LineWidth',2)
            end
            
        end
        %%%%%%%%%% INSERTION SORT END %%%%%%%%%%%
        
        %%%%%%%%% QUICK SORT %%%%%%%%%%%%%%%%%%
        function array = quick_sort(app,array,r,p)
            if r < p
                [array,q] = partition(app,array,r,p); %%call partition function
                array = quick_sort(app,array,r,q-1); %%call quick_sort function
                array = quick_sort(app,array,q+1,p); %%call quick_sort function
            end
            bar(app.UIAxes2,array,'FaceColor',"[0.4940 0.1840 0.5560]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
            title(app.UIAxes2,'Quick Sort');
            for i=1:numel(array)
                      text(app.UIAxes2,(i),array(i),num2str(array(i),'%0.0f'),...
                   'HorizontalAlignment','center',...
                   'VerticalAlignment','bottom','LineWidth',2)
            end
        end
        
        function [array,q] = partition(app,array,r,p)
            x=(1:length(array));
            i = (r-1); %%smaller elements index
            q = array(p); %%pivot
            for j=r:p %% smal index to high index
                if array(j) < q %% elements is smallere than or equal to pivot;
                    i = i+1; %% increasing smaller index 
                    temp1 = array(j); 
                    temp2 = array(i);
                    array(j) = temp2;
                    array(i) = temp1;
                    bar(app.UIAxes2,x,array,"black");
                    hold(app.UIAxes2,"on")
                    bar(app.UIAxes2,x(j+1),array(j+1),"blue")
                    bar(app.UIAxes2,x(j),array(j),"cyan")
                    bar(app.UIAxes2,x(i),array(i),"magenta")
                    hold(app.UIAxes2,"off")
                    pause((10-floor(app.SPEEDSlider.Value))*0.01);
                    
                end
            end
            temp3 = array(i+1) ;
            temp4 = array(p);
            array(i+1) = temp4;
            array(p) = temp3;
            q = i+1; 
        end
        %%%%%%%%% QUICK SORT END %%%%%%%%%%%%%%%
        
        
        %%%%%%% HEAP SORT END %%%%%%%%%%%%
        function array = heap_sort(app,array)
            x = (1:length(array));
            n = length(array); %%size
            array = max_heap_sort(app,array,n); %%call max_heap_sort func.
            
            for i = n:-1:2 %%one by one extract elements 
                key = array(i); %%swap
                array(i) = array(1);
                array(1) = key;
                n = n - 1;
                array = heapify(app,array,n,1);
                bar(app.UIAxes2,x,array,"black"); %%create bar chart
                hold(app.UIAxes2,"on")
                bar(app.UIAxes2,x(i),array(i),"cyan")
                hold(app.UIAxes2,"off")
                pause((10-floor(app.SPEEDSlider.Value))*0.01); %%speed button for bar animation  
            end
            bar(app.UIAxes2,array,'FaceColor',"[0.4940 0.1840 0.5560]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
            title(app.UIAxes2,'Heap Sort');
            for i=1:numel(array)
                      text(app.UIAxes2,(i),array(i),num2str(array(i),'%0.0f'),...
                   'HorizontalAlignment','center',...
                   'VerticalAlignment','bottom')
            end
        end
        
        function array = max_heap_sort(app,array,n) %%build max heap
            for i=floor(n/2):-1:1 %%last parent will be at ((n//2)-1) we can start at that location. 
                array = heapify(app,array,n,i); %%call heapify func.
            end
        end

        function array = heapify(app,array,n,i)
             x = (1:length(array));
             l = i*2; %%left = 2*i 
             r = (i*2)+1; %%right = 2*i +1
             hold(app.UIAxes2,"on") %%create bar chart
             bar(app.UIAxes2,x(i),array(i),"magenta")
             hold(app.UIAxes2,"off")
             if l <= n && array(l)>array(i) %%see if left child of root exists and is greater than root 
                 largest = l; %%make largest equal to 1
             else 
                 largest = i; %%make largest equal to i
             end
             if r <= n && array(r) > array(largest) %%see if right child of root exists and is greater than root  
                 largest = r;
             end
             if largest ~= i %%if largest is not equal i
                 key = array(i); %%swap 
                 array(i) = array(largest);
                 array(largest) = key;
                 array = heapify(app,array,n,largest); %%call heapify func.
             end
        end
        %%%%%%%%% HEAP SORT END %%%%%%%%%%%


        %%%%%%%% BUBBLE SORT END %%%%%%%%%%
        function array = bubbleSort(app,array) %%bubble sort algorithm function
            x = (1:length(array));
            bub = size (array,2); %%array length
            for i = 1:(bub+1) %%look at each items in the series one by one and check up how sort  
                for j = bub:-1:(i+1)
                    if array(j)<array(j-1)  %%compare the magnitudes of the values.
                        result = array(j); %%If the item is greater than its adjacent value, then swap 
                        array(j) = array(j-1);
                        array(j-1) = result;
                        bar(app.UIAxes2,x,array,"black"); %%create bar chart
                        hold(app.UIAxes2,"on")
                        bar(app.UIAxes2,x(j),array(j),"magenta")
                        bar(app.UIAxes2,x(j-1),array(j-1),"cyan")
                        hold(app.UIAxes2,"off")
                        pause((10-floor(app.SPEEDSlider.Value))*0.01); %%speed button for bar animation  
                    end
                end
            end
            bar(app.UIAxes2,array,'FaceColor',"[0.4940 0.1840 0.5560]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
            title(app.UIAxes2,'Bubble Sort');
            for i=1:numel(array)
                      text(app.UIAxes2,(i),array(i),num2str(array(i),'%0.0f'),...
                   'HorizontalAlignment','center',...
                   'VerticalAlignment','bottom')
            end
        end
        %%%%%%%%% BUBBLE SORT END %%%%%%%%%%%%%%%%
        
        
        %%%%%%%%%% BUCKET SORT END %%%%%%%%%%%%%%
        function array = bucket_sort(app,array)
            x_axis = (1:length(array));
            max_array_value = max(array); %%max element for array
            bucket_list = zeros(1,(max_array_value+1)); %%new empty output array
            for j = 1:numel(array) 
                bucket_list(array(j)) = (bucket_list(array(j)) + 1); 
            end
            x = 1;
            for i = 1:max_array_value+1
                for j = 1: bucket_list(i)
                    array(x) = i;
                    x = x + 1;
                    bar(app.UIAxes2,x_axis,array,"black"); %%create bar chart
                    hold(app.UIAxes2,"on")
                    bar(app.UIAxes2,x_axis(x-1),array(x-1),"cyan")
                    hold(app.UIAxes2,"off")
                    pause((10-floor(app.SPEEDSlider.Value))*0.01); %%speed button for bar animation
                end
            end
            bar(app.UIAxes2,array,'FaceColor',"[0.4940 0.1840 0.5560]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
            title(app.UIAxes2,'Bucket Sort');
            for i=1:numel(array)
                      text(app.UIAxes2,(i),array(i),num2str(array(i),'%0.0f'),...
                   'HorizontalAlignment','center',...
                   'VerticalAlignment','bottom')
            end
        end
       %%%%%%%% BUCKET SORT END %%%%%%%%%%%%%
        
       %%%%%%%% COUNTING SORT %%%%%%%%%%%%%%%%%%%
        function array = counting_sort(app,array)
             x = (1:length(array));
             max1 = max(array); %%max element for array
             min1 = min(array); %%min element for array
             count = zeros((max1-min1+1),1); %%new empty list for count.
             for num = array
                 count(num-min1+1) = count(num-min1+1) +1; %%add 1 to count.
             end
             for i=1:length(array)
                bar(app.UIAxes2,x,array,"black"); %%create bar chart
                hold(app.UIAxes2,"on");
                bar(app.UIAxes2,x(i),array(i),"cyan");
                hold(app.UIAxes2,"off");
                pause((10-floor(app.SPEEDSlider.Value))*0.01); %%speed button for bar animation
                
            end
            j = 1;
            for i = (min1:max1)     
                while( count(i-min1 +1) > 0)
                    array(j) = i;
                    j = j+1;
                    bar(app.UIAxes2,x,array,"black"); %%create bar chart
                    hold(app.UIAxes2,"on");
                    bar(app.UIAxes2,x(j-1),array(j-1),"magenta");
                    hold(app.UIAxes2,"off");
                    pause((10-floor(app.SPEEDSlider.Value))*0.01); %%speed button for bar animation
                    count(i - min1 + 1) = count(i - min1 + 1) - 1; 
                end
                bar(app.UIAxes2,array,'FaceColor',"[0.4940 0.1840 0.5560]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
                title(app.UIAxes2,'Counting Sort');
                for i=1:numel(array)
                      text(app.UIAxes2,(i),array(i),num2str(array(i),'%0.0f'),...
                   'HorizontalAlignment','center',...
                   'VerticalAlignment','bottom')
                end
            end
        end
         %%%%%%%% COUNTING SORT END %%%%%%%%%%%%%%%%%%%
         
         %%%%%%%%% RADIX SORT END %%%%%%%%%%%%%%%%%%%
        function array = radix_sort(app,array)
            max1 = max(array); %%max element for array
            exp = 1; 
            while max1/exp >= 1
                place = exp; %%do counting sort for every digit. Note that instead of passing digit number, exp is passed. exp is 10^i  where i is current digit number
                count = zeros(1,11); %%new empty array for count
                out = zeros(1,numel(array)); %%new empty array for radix 
                for j = 1:numel(array) 
                    count(mod(floor(array(j)/place),10)+1) = count(mod(floor(array(j)/place),10) +1) + 1; %%store count of occurrences in count[] 
                end
                
               
                for i = 2:11 
                    count(i) = count(i) + count(i-1); %%change count[i] so that count[i] now contains actual position of this digit in output array  
                end
           
                for j = numel(array):-1:1 
                    out(count(mod(floor(array(j)/place),10)+1)) = array(j);
                    count(mod(floor(array(j)/place),10)+1) = count(mod(floor(array(j)/place),10)+1) - 1;
                    bar(app.UIAxes2,out,"black"); %%create bar chart
                    hold(app.UIAxes2,"on")
                    bar(app.UIAxes2,count(mod(floor(array(j)/place),10) +1)+1,out(count(mod(floor(array(j)/place),10)+1)+1),"magenta")
                    hold(app.UIAxes2,"off")
                    pause((10-floor(app.SPEEDSlider.Value))*0.01); %%speed button for bar animation
                 
                end
                array = out;
                exp = exp * 10;
            end
            bar(app.UIAxes2,out,'FaceColor',"[0.4940 0.1840 0.5560]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
            title(app.UIAxes2,'Radix Sort');
            for i=1:numel(array)
                      text(app.UIAxes2,(i),array(i),num2str(array(i),'%0.0f'),...
                   'HorizontalAlignment','center',...
                   'VerticalAlignment','bottom')
            end
        end
        %%%%%%% RADIX SORT END %%%%%%%%%%%%%%%%
        
       
        function [ith_smallest] = qselect(app,array, k)
        low = 1;
        high = length(array);
        
        while(true)
            median = fix((low+high)/2);
            if (array(median) < array(high))
                temp = array(median) ;
                array(median) = array(high);
                array(high) = temp ;
            end
             if (array(median) < array(low))
                temp = array(median) ;
                array(median) = array(low);
                array(low) = temp ;
             end
             if (array(high) < array(low))
                temp = array(high) ;
                array(high) = array(low);
                array(low) = temp ;
             end
             index = array (high) ;
             i = low;
             for j = low:high-1
                 if(array(j)<= index)
                     temp = array(i);
                     array(i) = array(j);
                     array(j) = temp;
                     i = i+1;
                 end
             end
              temp = array(high); 
              array(high)= array(i); 
              array(i)=temp; 
              median=i-low+1;
            if(k<median)
              high =i-1;
            elseif(k>median)
              low =i+1; 
              k=k-median;
            else
              ith_smallest = array(i);
              return;
            end
  
        end
        end
        
        %%%%%% MERGE SORT %%%%%%%%%%
        function unsorted_array = Merge_Sort(app,unsorted_array,l,r) %%merge two subarrays of arr[]. 
            if(l<r)
                mid = floor((l+r)/2); %%Finding the midpoint of the array
                unsorted_array = Merge_Sort(app,unsorted_array,l,mid);
                unsorted_array = Merge_Sort(app,unsorted_array,mid+1,r);
                unsorted_array = Merge(app,unsorted_array,l,mid,r);
            end 
        end
        
        function unsorted_array = Merge(app,unsorted_array, l, mid, r)
            x = (1:length(unsorted_array));
            n1 = mid - l + 1;%%first half of array
            n2 = r - mid; %%second half of array.
            Left = zeros(1, n1+1); %left side of list.
            Right = zeros(1, n2+1); %right side of list.
            %%copy data to temp arrays left_side and right_side
            for i=1:n1
                Left(i) = unsorted_array(l+ i - 1);    
            end
            for j=1:n2
                Right(j) = unsorted_array(mid+j);
            end
            Left(n1+1) = inf; 
            Right(n2+1) = inf;
            i=1;
            j=1;
            
            for k=l:r

                if Left(i)<=Right(j)  %%checking if any element was right
                    unsorted_array(k)=Left(i); 
                    i=i+1;
                    
                else %%checking if any element was left
                    unsorted_array(k)=Right(j);
                    j = j+1;
                end
                bar(app.UIAxes2,x,unsorted_array,"black"); %%create bar chart
                hold(app.UIAxes2,"on");
                bar(app.UIAxes2,x(k),unsorted_array(k),"magenta");
                hold(app.UIAxes2,"off");
                pause((10-floor(app.SPEEDSlider.Value))*0.01);  %%speed button for bar animation
            end
            bar(app.UIAxes2,unsorted_array,'FaceColor',"[0.4940 0.1840 0.5560]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
            title(app.UIAxes2,'Merge Sort');
            for i=1:numel(unsorted_array)
                      text(app.UIAxes2,(i),unsorted_array(i),num2str(unsorted_array(i),'%0.0f'),...
                   'HorizontalAlignment','center',...
                   'VerticalAlignment','bottom')
            end
        end
         %%%%%% MERGE SORT END %%%%%%%%%%
        
         %%%%%%%%% SELECTION SORT %%%%%%%%%%%%
         function unsorted_arrays = Selection_Sort(app,unsorted_array)
                x = (1:length(unsorted_array));
                n = size(unsorted_array,2);
                for i=1:n %%find the minimum element in remaining unsorted array 
                   min_index=i;%%we first assume that the first element is the lowest
                   for j=i:n
                       if unsorted_array(j) < unsorted_array(min_index) %%update the min_index if the element at j is lower than it
                          min_index=j;
                       end
                   end
                
                if min_index ~= i
                    temp=unsorted_array(i); %%swap the found minimum element with the first element  
                    unsorted_array(i)=unsorted_array(min_index);
                    unsorted_array(min_index)=temp;
                    
                    bar(app.UIAxes2,x,unsorted_array,"black"); %%create bar chart
                    hold(app.UIAxes2,"on");
                    bar(app.UIAxes2,x(min_index),unsorted_array(min_index),"cyan");
                    hold(app.UIAxes2,"off");
                    pause((10-floor(app.SPEEDSlider.Value))*0.01); %%speed button for bar animation
                end
                end
                bar(app.UIAxes2,unsorted_array,'FaceColor',"[0.4940 0.1840 0.5560]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
                title(app.UIAxes2,'Selection Sort');
                for i=1:numel(unsorted_array)
                          text(app.UIAxes2,(i),unsorted_array(i),num2str(unsorted_array(i),'%0.0f'),...
                       'HorizontalAlignment','center',...
                       'VerticalAlignment','bottom')
           
                end
                unsorted_arrays = unsorted_array;
         end
         %%%%%%%%% SELECTION SORT END %%%%%%%%%%%%
         
         
        %%%%%%%% SHELL SORT %%%%%%%%%%%%%%%%%%%
        function unsorted_arrays = Shell_Sort(app,unsorted_array)
                x = (1:length(unsorted_array));
                n = size(unsorted_array,2);
                increment = floor(n/2); %%start with a big increment, then reduce the increment
                while increment > 0
                    for i = increment+1:n
                        temp = unsorted_array(i); %%add array[i] to the elements that have been increment sorted save aray[i] in temp and make a hole at position i  
                        j = i; %%shift earlier increment-sorted elements up until the correct location for array[i] is found 
                        while (j >= increment+1) && (unsorted_array(j-increment) > temp)
                            unsorted_array(j) = unsorted_array(j-increment);
                            j = j - increment;
                        end
                        unsorted_array(j) = temp; %%put temp in its correct location 
                        bar(app.UIAxes2,x,unsorted_array,"black"); %%create bar chart
                        hold(app.UIAxes2,"on");
                        bar(app.UIAxes2,x(i),unsorted_array(i),"magenta");
                        bar(app.UIAxes2,x(j),unsorted_array(j),"cyan");
                        hold(app.UIAxes2,"off");
                        pause((10-floor(app.SPEEDSlider.Value))*0.01); %%speed button for bar animation
                    end
                    increment = floor(increment/2);
                    
                end
                bar(app.UIAxes2,unsorted_array,'FaceColor',"[0.4940 0.1840 0.5560]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
                title(app.UIAxes2,'Shell Sort');
                for i=1:numel(unsorted_array)
                          text(app.UIAxes2,(i),unsorted_array(i),num2str(unsorted_array(i),'%0.0f'),...
                       'HorizontalAlignment','center',...
                       'VerticalAlignment','bottom')
                end
           
                unsorted_arrays = unsorted_array;
        end
        %%%%%%%% SHELL SORT END %%%%%%%%%%%%%%%%%%%

        
        function results = voice(app,String)
            caUserInput = {String};
            caUserInput = char(caUserInput);
            NET.addAssembly('System.Speech');
            obj = System.Speech.Synthesis.SpeechSynthesizer;
            obj.Volume = 100;
            Speak(obj, caUserInput)
            
        end
        function unsorted_array = Insertion(app,unsorted_array) %%insertion sort algorithm function
            length = size(unsorted_array,2); %%array length
            for i = 2:length 
                key = unsorted_array(i); %%set a key value.
                j = i - 1; %%run through the list of items.
                
                while j >0 && unsorted_array(j)> key %%find the correct position.compare the smaller and greater of the numbers. 
                    unsorted_array(j+1) = unsorted_array(j); %%Do this only if key is smaller than other values, shift the value one position to the left
                    j = j - 1; %%reposition from right to left.
                end
                unsorted_array(j+1) = key; %%when finish shifting the number items, our key is sorted correctly.
            end 
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: CALCULATEButton
        function CALCULATEButtonPushed(app, event)
        value = app.EditField.Value;
        fibonacci(1) = 0; %First Fibonacci number is equal to 0.
        fibonacci(2) = 1; %Second Fibonacci number is equal to 1.
        fibonacci(3) = 1;
        for i = 4:value
            fibonacci(i) = fibonacci(i-1)+fibonacci(i-2); %%to go by adding the elements together.
        end
        app.TextArea.Value = num2str(fibonacci); %%print text area.
        answer = fibonacci(value);
        app.fibonacci_answer.Value = num2str(answer);
        bar(app.UIAxes7,fibonacci,'FaceColor',"[0.8500 0.3250 0.0980]","EdgeColor","black",'LineWidth',2)
        title(app.UIAxes7,'Fibonacci Series');
        for i=1:numel(fibonacci)
                          text(app.UIAxes7,(i),fibonacci(i),num2str(fibonacci(i),'%0.0f'),...
                       'HorizontalAlignment','center',...
                       'VerticalAlignment','bottom')
         end
        end

        % Callback function
        function KnobValueChanging(app, event)
            app.changingValue = event.Value;
            app.changingValue =fix(app.changingValue);
            app.EditField.Value = app.changingValue;
        end

        % Button pushed function: SeethefibonacciratioButton
        function SeethefibonacciratioButtonPushed(app, event)
        fibonacci =[1 1]; 
        golden_ratio = [1 1];
        x=1;
        while fibonacci(x) < 10000
        fibonacci(x+2)=fibonacci(x+1)+fibonacci(x); %%to go by adding the elements together.
        golden_ratio(x+2) = fibonacci(x+2)/fibonacci(x+1); %%for golden ratio number formula.
        x=x+1;
        end
        plot(app.UIAxes,golden_ratio); %%draw plot
        title(app.UIAxes,' Fibonacci Series and Golden Ratio');
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.UITable.Data(:) = [];
            app.UITable2.Data(:) = [];
            app.UITable3.Data(:) = [];
            app.row.Value = 1;
            app.col1androw2.Value = 1;
            app.col2.Value = 1;
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
        
            global row
            global col
            global col2
            global row2
            global matrix1
            global matrix2
            row = app.row.Value;
            col = app.col1androw2.Value;
            col2 = app.col2.Value;
            row2 = app.SecondMatrixRowEditField.Value;
            matrix2 = round(-100+1000*rand((row2),(col2))); %%create random matrix1
            matrix1 = round(-100+1000*rand((row),(col))); %%create random matrix2
            app.UITable.Data = matrix1;
            app.UITable2.Data = matrix2;
            get(app.UITable,"Data");
            get(app.UITable2,"Data");
            app.UITable3.Data(:) = [];
          
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global row
            global col
            global col2
            global row2
            global matrix1
            global matrix2
            global matrix3
            matrix1 = app.UITable.Data;
            matrix2 = app.UITable2.Data;
            if isempty(matrix1) && isempty(matrix2)
                 msgbox('Please check your matrix','Error','error')
            else
                if col ~= row2 
                msgbox('For matrix multiplication, the row of matrix one must be equal to the column of the matrix two','Error','error')
                else
         
                    [row , col] = size(matrix1); %%matrix1 size determination
                    [row2, col2] = size(matrix2); %%matrix2 size determination
                    matrix3 = zeros(row,col2);
                    for i = 1:row 
                        for j = 1:col2 
                            for k = 1:col 
                                matrix3(i,j) = matrix3(i,j) + (matrix1(i,k)* matrix2(k,j)); %%Create the result matrix
                                
                            end
                        end
                    end
                    app.UITable3.Data = matrix3; 
                end
         
               
            end


        end

        % Button pushed function: RANDOMARRAYButton_2
        function RANDOMARRAYButton_2Pushed(app, event)

          try
                value1 = app.Array_size_14.Value;
                value2 = app.Upper_Bound_13.Value;
                value3 = app.Lower_Bound_12.Value;
                if (value2 == 0 && value3 == 0) || value1 == 0 %%error for not input
                    msgbox('Please check your array.','Error','error')
                else
                    if value3 >= value2 
                        msgbox('Should be upper range greater than lower range..','Error','error')
                    else
                            if (abs(value2-value3)<value1)
                                msgbox('To obtain a unique array, the difference between the lower and upper bound must be greater than the length of the array...','Error','error')
                            else
                                app.unsorted_array=[]; %%empty array list
                                while 1
                                    app.unsorted_array=[app.unsorted_array randi([value3 value2],1,1)]; %%create use randi for random array
                                    app.unsorted_array = unique(app.unsorted_array);
                                    
                                    if length(app.unsorted_array) == value1
                                        break
                                    end
                                end
                                app.unsorted_array = app.unsorted_array(randperm(length(app.unsorted_array))); %%create random array for not see same numbers
                                app.TextArea_2.Value = num2str(app.unsorted_array); %%unsorted array
                                app.TextArea_3.Value = num2str(sort(app.unsorted_array)); %%unsorted array 
                            end
                    end
                     bar(app.UIAxes4,app.unsorted_array,'FaceColor',"black",'EdgeColor',"yellow",'LineWidth',2);
                     for i=1:numel(app.unsorted_array) 
                     title(app.UIAxes4,'Unsorted Array');
                      text(app.UIAxes4,(i),app.unsorted_array(i),num2str(app.unsorted_array(i),'%0.0f'),...
                                       'HorizontalAlignment','center',...
                                        'VerticalAlignment','bottom')
                         
                     end
                end            
            catch
                msgbox('Please, check it array', 'Error','error');
          end
        end

        % Callback function
        function SliderValueChanging(app, event)
            app.changingValue = event.Value;
            app.changingValue = fix(app.changingValue);
            app.EditField_2.Value = app.changingValue;
        end

        % Button pushed function: SEARCHNUMBERINDEXButton
        function SEARCHNUMBERINDEXButtonPushed(app, event)
            try
            
            app.unsorted_array = sort(app.unsorted_array);
            x_axis = 1:length(app.unsorted_array);
            find_number = app.Spinner.Value; 
            binary = binarySearch(app,app.unsorted_array,find_number,0); %%call binary func.
            app.EditField_3.Value = binary; %%print index
            
            bar(app.UIAxes_2,x_axis,app.unsorted_array,"black",'EdgeColor',"yellow",'LineWidth',2); %%create bar chart
            hold(app.UIAxes_2,"on")
            bar(app.UIAxes_2,x_axis(binary),app.unsorted_array(binary),"yellow")
            hold(app.UIAxes_2,"off")
            for i=1:numel(app.unsorted_array) 
            title(app.UIAxes_2,'Sorted Array');
            text(app.UIAxes_2,(i),app.unsorted_array(i),num2str(app.unsorted_array(i),'%0.0f'),...
                                       'HorizontalAlignment','center',...
                                        'VerticalAlignment','bottom')
            end


            catch
                msgbox('Wrong Number!', 'Error','error');
                bar(app.UIAxes_2,x_axis,app.unsorted_array,"black",'EdgeColor',"yellow",'LineWidth',2); %%create bar chart
                for i=1:numel(app.unsorted_array) 
                title(app.UIAxes_2,'Sorted Array');
                text(app.UIAxes_2,(i),app.unsorted_array(i),num2str(app.unsorted_array(i),'%0.0f'),...
                                       'HorizontalAlignment','center',...
                                        'VerticalAlignment','bottom')
                end
            end
        end

        % Callback function
        function RANGEKnobValueChanging(app, event)
            app.changingValue_arrayrange = event.Value;
            app.changingValue_arrayrange=fix(app.changingValue_arrayrange);
            app.lower_bound.Value = app.changingValue_arrayrange;
        end

        % Callback function
        function ARRAYSZEKnobValueChanging(app, event)
            app.changingValue_arraysize = event.Value;
            app.changingValue_arraysize=fix(app.changingValue_arraysize);
            app.array_size.Value = app.changingValue_arraysize;
        end

        % Button pushed function: RANDOMARRAYButton
        function RANDOMARRAYButtonPushed(app, event)
            try
                 same = app.Switch.Value;
                 value1 = app.array_size.Value;
                 value2 = app.upper_bound.Value;
                 value3 = app.lower_bound.Value;
                if app.SEEDCheckBox.Value == 0
                   
                    if (value2 == 0 && value3 == 0) || value1 == 0
                        msgbox('Please check your array.','Error','error')
                    else
                        if value3 >= value2 
                            msgbox('Should be upper range greater than lower range..','Error','error')
                        else
                            if strcmp(same,'Not see same numbers')
                                if (abs(value2-value3)<value1)
                                    msgbox('To obtain a unique array, the difference between the lower and upper bound must be greater than the length of the array...','Error','error')
                                else
                                    app.unsorted_array=[];
                                    while 1
                                        app.unsorted_array=[app.unsorted_array randi([value3 value2],1,1)];
                                        app.unsorted_array = unique(app.unsorted_array);
                                        
                                        if length(app.unsorted_array) == value1
                                            break
                                        end
                                    end
                                    app.unsorted_array = app.unsorted_array(randperm(length(app.unsorted_array)));
                                end
                            else
                                 app.unsorted_array=randi([value3 value2],1,value1);
                            end
                            bar(app.UIAxes2,app.unsorted_array,'FaceColor',"[0.4940 0.1840 0.5560]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
                            title(app.UIAxes2,'Sort Algorithm Animation');
                            for i=1:numel(app.unsorted_array)
                                      text(app.UIAxes2,(i),app.unsorted_array(i),num2str(app.unsorted_array(i),'%0.0f'),...
                                      'HorizontalAlignment','center',...
                                      'VerticalAlignment','bottom')
                            end
                        end
                    end
                   
                else
                    rng('default');
                    rng(1721);
                    if (value2 == 0 && value3 == 0) || value1 == 0
                        msgbox('Please check your array.','Error','error')
                    else
                        if value3 >= value2 
                            msgbox('Should be upper range greater than lower range..','Error','error')
                        else
                            if strcmp(same,'Not see same numbers')
                                if (abs(value2-value3)<value1)
                                    msgbox('To obtain a unique array, the difference between the lower and upper bound must be greater than the length of the array...','Error','error')
                                else
                                    app.unsorted_array=[];
                                    while 1
                                        app.unsorted_array=[app.unsorted_array randi([value3 value2],1,1)];
                                        app.unsorted_array = unique(app.unsorted_array);
                                        
                                        if length(app.unsorted_array) == value1
                                            break
                                        end
                                    end
                                    app.unsorted_array = app.unsorted_array(randperm(length(app.unsorted_array)));
                                end
                            else
                                 app.unsorted_array=randi([value3 value2],1,value1);
                            end
                            bar(app.UIAxes2,app.unsorted_array,'FaceColor',"[0.4940 0.1840 0.5560]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
                            title(app.UIAxes2,'Sort Algorithm Animation');
                            for i=1:numel(app.unsorted_array)
                                      text(app.UIAxes2,(i),app.unsorted_array(i),num2str(app.unsorted_array(i),'%0.0f'),...
                                      'HorizontalAlignment','center',...
                                      'VerticalAlignment','bottom')
                            end
                        end
                    end
                end
                
            catch
                msgbox('Please, check it array', 'Error','error');
            end
            
        end

        % Button pushed function: INSERTIONSORTButton
        function INSERTIONSORTButtonPushed(app, event)
            if isempty(app.unsorted_array)
                msgbox('Please set the array..','Error','error')
            else
                set(app.COUNTINGSORTButton,'Enable','off');
                set(app.BUCKETSORTButton,'Enable','off');
                set(app.RADIXSORTButton,'Enable','off');
                set(app.HEAPSORTButton,'Enable','off');
                set(app.QUICKSORTButton,'Enable','off');
                set(app.MERGESORTButton,'Enable','off');
                set(app.BUBBLESORTButton,'Enable','off');
                set(app.INSERTIONSORTButton,'Enable','off');
                set(app.RANDOMARRAYButton,'Enable','off');
                set(app.DEFAULTDATAButton,'Enable','off');
                set(app.SHELLSORTButton,'Enable','off');
                set(app.SELECTIONSORTButton,'Enable','off');
                set(app.CREATEARRAYButton_2,'Enable','off');
                set(app.TIMECOMPARISONButton,'Enable','off');
                Insertion_sort(app,app.unsorted_array)
                %app.TextArea_5.Value = num2str(app.unsorted_array);
                set(app.COUNTINGSORTButton,'Enable','on');
                set(app.BUCKETSORTButton,'Enable','on');
                set(app.RADIXSORTButton,'Enable','on');
                set(app.HEAPSORTButton,'Enable','on');
                set(app.QUICKSORTButton,'Enable','on');
                set(app.MERGESORTButton,'Enable','on');
                set(app.BUBBLESORTButton,'Enable','on');
                set(app.INSERTIONSORTButton,'Enable','on');
                set(app.RANDOMARRAYButton,'Enable','on');
                set(app.DEFAULTDATAButton,'Enable','on');
                set(app.SHELLSORTButton,'Enable','on');
                set(app.SELECTIONSORTButton,'Enable','on');
                set(app.CREATEARRAYButton_2,'Enable','on');
                set(app.TIMECOMPARISONButton,'Enable','on');
           
            end
            String = 'The algorithm has been sorted successfully.';
            voice(app,String)
        end

        % Button pushed function: QUICKSORTButton
        function QUICKSORTButtonPushed(app, event)
            if isempty(app.unsorted_array)
                msgbox('Please set the array..','Error','error')
            else
                set(app.COUNTINGSORTButton,'Enable','off');
                set(app.BUCKETSORTButton,'Enable','off');
                set(app.RADIXSORTButton,'Enable','off');
                set(app.HEAPSORTButton,'Enable','off');
                set(app.QUICKSORTButton,'Enable','off');
                set(app.MERGESORTButton,'Enable','off');
                set(app.BUBBLESORTButton,'Enable','off');
                set(app.INSERTIONSORTButton,'Enable','off');
                set(app.RANDOMARRAYButton,'Enable','off');
                set(app.DEFAULTDATAButton,'Enable','off');
                set(app.SHELLSORTButton,'Enable','off');
                set(app.SELECTIONSORTButton,'Enable','off');
                set(app.CREATEARRAYButton_2,'Enable','off');
                set(app.TIMECOMPARISONButton,'Enable','off');
                quick_sort(app,app.unsorted_array,1,length(app.unsorted_array))
                set(app.COUNTINGSORTButton,'Enable','on');
                set(app.BUCKETSORTButton,'Enable','on');
                set(app.RADIXSORTButton,'Enable','on');
                set(app.HEAPSORTButton,'Enable','on');
                set(app.QUICKSORTButton,'Enable','on');
                set(app.MERGESORTButton,'Enable','on');
                set(app.BUBBLESORTButton,'Enable','on');
                set(app.INSERTIONSORTButton,'Enable','on');
                set(app.RANDOMARRAYButton,'Enable','on');
                set(app.DEFAULTDATAButton,'Enable','on');
                set(app.SHELLSORTButton,'Enable','on');
                set(app.SELECTIONSORTButton,'Enable','on');
                set(app.CREATEARRAYButton_2,'Enable','on');
                set(app.TIMECOMPARISONButton,'Enable','on');
            end
            String = 'The algorithm has been sorted successfully.';
            voice(app,String)
        end

        % Button pushed function: HEAPSORTButton
        function HEAPSORTButtonPushed(app, event)
            if isempty(app.unsorted_array)
                msgbox('Please set the array..','Error','error')
            else
                set(app.COUNTINGSORTButton,'Enable','off');
                set(app.BUCKETSORTButton,'Enable','off');
                set(app.RADIXSORTButton,'Enable','off');
                set(app.HEAPSORTButton,'Enable','off');
                set(app.QUICKSORTButton,'Enable','off');
                set(app.MERGESORTButton,'Enable','off');
                set(app.BUBBLESORTButton,'Enable','off');
                set(app.INSERTIONSORTButton,'Enable','off');
                set(app.RANDOMARRAYButton,'Enable','off');
                set(app.DEFAULTDATAButton,'Enable','off');
                set(app.SHELLSORTButton,'Enable','off');
                set(app.SELECTIONSORTButton,'Enable','off');
                set(app.CREATEARRAYButton_2,'Enable','off');
                set(app.TIMECOMPARISONButton,'Enable','off');
                heap_sort(app,app.unsorted_array)
                set(app.COUNTINGSORTButton,'Enable','on');
                set(app.BUCKETSORTButton,'Enable','on');
                set(app.RADIXSORTButton,'Enable','on');
                set(app.HEAPSORTButton,'Enable','on');
                set(app.QUICKSORTButton,'Enable','on');
                set(app.MERGESORTButton,'Enable','on');
                set(app.BUBBLESORTButton,'Enable','on');
                set(app.INSERTIONSORTButton,'Enable','on');
                set(app.RANDOMARRAYButton,'Enable','on');
                set(app.DEFAULTDATAButton,'Enable','on');
                set(app.SHELLSORTButton,'Enable','on');
                set(app.SELECTIONSORTButton,'Enable','on');
                set(app.CREATEARRAYButton_2,'Enable','on');
                set(app.TIMECOMPARISONButton,'Enable','on');
                String = 'The algorithm has been sorted successfully.';
                voice(app,String)
                
            end
        end

        % Button pushed function: BUBBLESORTButton
        function BUBBLESORTButtonPushed(app, event)
            if isempty(app.unsorted_array)
                msgbox('Please set the array..','Error','error')
            else
                set(app.COUNTINGSORTButton,'Enable','off');
                set(app.BUCKETSORTButton,'Enable','off');
                set(app.RADIXSORTButton,'Enable','off');
                set(app.HEAPSORTButton,'Enable','off');
                set(app.QUICKSORTButton,'Enable','off');
                set(app.MERGESORTButton,'Enable','off');
                set(app.BUBBLESORTButton,'Enable','off');
                set(app.INSERTIONSORTButton,'Enable','off');
                set(app.RANDOMARRAYButton,'Enable','off');
                set(app.DEFAULTDATAButton,'Enable','off');
                set(app.SHELLSORTButton,'Enable','off');
                set(app.SELECTIONSORTButton,'Enable','off');
                set(app.CREATEARRAYButton_2,'Enable','off');
                set(app.TIMECOMPARISONButton,'Enable','off');
                bubbleSort(app,app.unsorted_array)
                set(app.COUNTINGSORTButton,'Enable','on');
                set(app.BUCKETSORTButton,'Enable','on');
                set(app.RADIXSORTButton,'Enable','on');
                set(app.HEAPSORTButton,'Enable','on');
                set(app.QUICKSORTButton,'Enable','on');
                set(app.MERGESORTButton,'Enable','on');
                set(app.BUBBLESORTButton,'Enable','on');
                set(app.INSERTIONSORTButton,'Enable','on');
                set(app.RANDOMARRAYButton,'Enable','on');
                set(app.DEFAULTDATAButton,'Enable','on');
                set(app.SHELLSORTButton,'Enable','on');
                set(app.SELECTIONSORTButton,'Enable','on');
                set(app.CREATEARRAYButton_2,'Enable','on');
                set(app.TIMECOMPARISONButton,'Enable','on');
            end
            String = 'The algorithm has been sorted successfully.';
            voice(app,String)
        end

        % Button pushed function: BUCKETSORTButton
        function BUCKETSORTButtonPushed(app, event)
            if isempty(app.unsorted_array)
                msgbox('Please set the array..','Error','error')
            else
                set(app.COUNTINGSORTButton,'Enable','off');
                set(app.BUCKETSORTButton,'Enable','off');
                set(app.RADIXSORTButton,'Enable','off');
                set(app.HEAPSORTButton,'Enable','off');
                set(app.QUICKSORTButton,'Enable','off');
                set(app.MERGESORTButton,'Enable','off');
                set(app.BUBBLESORTButton,'Enable','off');
                set(app.INSERTIONSORTButton,'Enable','off');
                set(app.RANDOMARRAYButton,'Enable','off');
                set(app.DEFAULTDATAButton,'Enable','off');
                set(app.SHELLSORTButton,'Enable','off');
                set(app.SELECTIONSORTButton,'Enable','off');
                set(app.CREATEARRAYButton_2,'Enable','off');
                bucket_sort(app,app.unsorted_array)
                set(app.COUNTINGSORTButton,'Enable','on');
                set(app.BUCKETSORTButton,'Enable','on');
                set(app.RADIXSORTButton,'Enable','on');
                set(app.HEAPSORTButton,'Enable','on');
                set(app.QUICKSORTButton,'Enable','on');
                set(app.MERGESORTButton,'Enable','on');
                set(app.BUBBLESORTButton,'Enable','on');
                set(app.INSERTIONSORTButton,'Enable','on');
                set(app.RANDOMARRAYButton,'Enable','on');
                set(app.DEFAULTDATAButton,'Enable','on');
                set(app.SHELLSORTButton,'Enable','on');
                set(app.SELECTIONSORTButton,'Enable','on');
                set(app.CREATEARRAYButton_2,'Enable','on');
            end
            String = 'The algorithm has been sorted successfully.';
            voice(app,String)
        end

        % Button pushed function: COUNTINGSORTButton
        function COUNTINGSORTButtonPushed(app, event)
            if isempty(app.unsorted_array)
                msgbox('Please set the array..','Error','error')
            else
                set(app.COUNTINGSORTButton,'Enable','off');
                set(app.BUCKETSORTButton,'Enable','off');
                set(app.RADIXSORTButton,'Enable','off');
                set(app.HEAPSORTButton,'Enable','off');
                set(app.QUICKSORTButton,'Enable','off');
                set(app.MERGESORTButton,'Enable','off');
                set(app.BUBBLESORTButton,'Enable','off');
                set(app.INSERTIONSORTButton,'Enable','off');
                set(app.RANDOMARRAYButton,'Enable','off');
                set(app.DEFAULTDATAButton,'Enable','off');
                set(app.SHELLSORTButton,'Enable','off');
                set(app.SELECTIONSORTButton,'Enable','off');
                set(app.CREATEARRAYButton_2,'Enable','off');
                set(app.TIMECOMPARISONButton,'Enable','off');
                counting_sort(app,app.unsorted_array)
                set(app.COUNTINGSORTButton,'Enable','on');
                set(app.BUCKETSORTButton,'Enable','on');
                set(app.RADIXSORTButton,'Enable','on');
                set(app.HEAPSORTButton,'Enable','on');
                set(app.QUICKSORTButton,'Enable','on');
                set(app.MERGESORTButton,'Enable','on');
                set(app.BUBBLESORTButton,'Enable','on');
                set(app.INSERTIONSORTButton,'Enable','on');
                set(app.RANDOMARRAYButton,'Enable','on');
                set(app.DEFAULTDATAButton,'Enable','on');
                set(app.SHELLSORTButton,'Enable','on');
                set(app.SELECTIONSORTButton,'Enable','on');
                set(app.CREATEARRAYButton_2,'Enable','on');
                set(app.TIMECOMPARISONButton,'Enable','on');
            end
            String = 'The algorithm has been sorted successfully.';
            voice(app,String)
        end

        % Button pushed function: RADIXSORTButton
        function RADIXSORTButtonPushed(app, event)
            if isempty(app.unsorted_array)
                msgbox('Please set the array..','Error','error')
            else
                set(app.COUNTINGSORTButton,'Enable','off');
                set(app.BUCKETSORTButton,'Enable','off');
                set(app.RADIXSORTButton,'Enable','off');
                set(app.HEAPSORTButton,'Enable','off');
                set(app.QUICKSORTButton,'Enable','off');
                set(app.MERGESORTButton,'Enable','off');
                set(app.BUBBLESORTButton,'Enable','off');
                set(app.INSERTIONSORTButton,'Enable','off');
                set(app.RANDOMARRAYButton,'Enable','off');
                set(app.DEFAULTDATAButton,'Enable','off');
                set(app.SHELLSORTButton,'Enable','off');
                set(app.SELECTIONSORTButton,'Enable','off');
                set(app.CREATEARRAYButton_2,'Enable','off');
                set(app.TIMECOMPARISONButton,'Enable','off');
                radix_sort(app,app.unsorted_array)
                set(app.COUNTINGSORTButton,'Enable','on');
                set(app.BUCKETSORTButton,'Enable','on');
                set(app.RADIXSORTButton,'Enable','on');
                set(app.HEAPSORTButton,'Enable','on');
                set(app.QUICKSORTButton,'Enable','on');
                set(app.MERGESORTButton,'Enable','on');
                set(app.BUBBLESORTButton,'Enable','on');
                set(app.INSERTIONSORTButton,'Enable','on');
                set(app.RANDOMARRAYButton,'Enable','on');
                set(app.DEFAULTDATAButton,'Enable','on');
                set(app.SHELLSORTButton,'Enable','on');
                set(app.SELECTIONSORTButton,'Enable','on');
                set(app.CREATEARRAYButton_2,'Enable','on');
                set(app.TIMECOMPARISONButton,'Enable','on');
            end
            String = 'The algorithm has been sorted successfully.';
            voice(app,String)
        end

        % Callback function
        function RANGEKnob_2ValueChanged(app, event)
            app.changingValueRange = event.Value;
            app.changingValueRange=fix(app.changingValueRange);
            app.upper_bound_6.Value=app.changingValueRange;
            
        end

        % Callback function
        function ARRAYSZEKnob_2ValueChanged(app, event)
            app.changingValueSize = event.Value;
            app.changingValueSize=fix(app.changingValueSize);
            app.lower_bound_7.Value=app.changingValueSize;
        end

        % Button pushed function: RandomArrayButton
        function RandomArrayButtonPushed(app, event)

          try
               same = app.IdonotwantseesamenumbersCheckBox.Value; %checkbox value
                value1 = app.array_size_16.Value;
                value2 = app.upper_bound_6.Value;
                value3 = app.lower_bound_7.Value;
                if (value2 == 0 && value3 == 0) || value1 == 0  %%%error for not input
                    msgbox('Please check your array.','Error','error')
                else
                    if value3 >= value2 
                        msgbox('Should be upper range greater than lower range..','Error','error')
                    else
                        if same 
                            if (abs(value2-value3)<value1)
                                msgbox('To obtain a unique array, the difference between the lower and upper bound must be greater than the length of the array...','Error','error')
                            else
                                app.unsorted_array=[];
                                while 1
                                    app.unsorted_array=[app.unsorted_array randi([value3 value2],1,1)]; %%different random array
                                    app.unsorted_array = unique(app.unsorted_array); %%unique function
                                    
                                    if length(app.unsorted_array) == value1
                                        break
                                    end
                                end
                                app.unsorted_array = app.unsorted_array(randperm(length(app.unsorted_array))); %%different random array and not same number
                                app.TextArea_4.Value = num2str(app.unsorted_array);
                                app.TextArea_6.Value = num2str(sort(app.unsorted_array));
                            end
                        else
                             app.unsorted_array=randi([value3 value2],1,value1); %%different random array
                             app.TextArea_4.Value = num2str(app.unsorted_array);
                             app.TextArea_6.Value = num2str(sort(app.unsorted_array));
                        end
                          bar(app.UIAxes5,app.unsorted_array,'FaceColor',"black",'EdgeColor',"green",'LineWidth',2);
                          for i=1:numel(app.unsorted_array) 
                          title(app.UIAxes5,'Unsorted Array');
                          text(app.UIAxes5,(i),app.unsorted_array(i),num2str(app.unsorted_array(i),'%0.0f'),...
                                       'HorizontalAlignment','center',...
                                        'VerticalAlignment','bottom')
                          end
                    end
                end
            catch
                msgbox('Please, check it array', 'Error','error');
          end
        end

        % Button pushed function: ithsmallestButton
        function ithsmallestButtonPushed(app, event)
            try
            unsorted_arrays = sort(app.unsorted_array);
            x_axis = 1:length(unsorted_arrays);
            k = app.EditField_8.Value;
            results = qselect(app,app.unsorted_array,k);
            app.TextArea_5.Value = num2str(results);
            bar(app.UIAxes6,x_axis,unsorted_arrays,"black");
            hold(app.UIAxes6,"on")
            bar(app.UIAxes6, x_axis(k),unsorted_arrays(k),'FaceColor',"black",'EdgeColor',"green",'LineWidth',2);
            hold(app.UIAxes6,"off")
            for i=1:numel(unsorted_arrays) 
                title(app.UIAxes6,'Sorted Array');
                text(app.UIAxes6,(i),unsorted_arrays(i),num2str(unsorted_arrays(i),'%0.0f'),...
                               'HorizontalAlignment','center',...
                                'VerticalAlignment','bottom')
            end
                          
            catch
                 msgbox('Check your index or array....', 'Error','error');
                 bar(app.UIAxes6,x_axis,unsorted_arrays,"black");
                 for i=1:numel(unsorted_arrays) 
                 title(app.UIAxes6,'Sorted Array');
                 text(app.UIAxes6,(i),unsorted_arrays(i),num2str(unsorted_arrays(i),'%0.0f'),...
                               'HorizontalAlignment','center',...
                                'VerticalAlignment','bottom')
                 end
                 
            end
          
        end

        % Button pushed function: ClearButton_2
        function ClearButton_2Pushed(app, event)
            %%clear button%%
            app.TextArea_4.Value = '';
            app.TextArea_5.Value = '';
            app.TextArea_6.Value = '';
            app.array_size_16.Value = 0;
            app.upper_bound_6.Value = 0;
            app.lower_bound_7.Value = 0;
            app.EditField_8.Value = 1;
            cla(app.UIAxes5)
            cla(app.UIAxes6)
            app.unsorted_array = [];


        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            %%clear binary search
        app.TextArea_2.Value = '';
        app.TextArea_3.Value = '';
        cla(app.UIAxes_2);
        cla(app.UIAxes4);
        app.Array_size_14.Value = 0;
        app.Upper_Bound_13.Value = 0;
        app.Lower_Bound_12.Value = 0;
        app.Spinner.Value = 0;
        app.EditField_3.Value = 0;
        app.unsorted_array = [];
        end

        % Button pushed function: ClearButton_4
        function ClearButton_4Pushed(app, event)
            app.TextArea.Value = '';
            cla(app.UIAxes7)
            cla(app.UIAxes)
            app.EditField.Value = 1;
            app.fibonacci_answer.Value = '';
        end

        % Callback function
        function Button_2Pushed(app, event)
          web('https://www.ikcu.edu.tr/', '-browser')
        end

        % Callback function
        function INFORMATIONButtonPushed(app, event)
             app.CallingApp = app1; 
        end

        % Callback function
        function EXITButtonPushed(app, event)
            closereq;
        end

        % Callback function
        function RANGEKnob_3ValueChanged(app, event)
            app.changingValueRange = event.Value;
            app.changingValueRange=fix(app.changingValueRange);
            app.EditField_9.Value=app.changingValueRange;
        end

        % Callback function
        function ARRAYSZEKnob_3ValueChanged(app, event)
            app.changingValueSize = event.Value;
            app.changingValueSize=fix(app.changingValueSize);
            app.EditField_10.Value=app.changingValueSize;
        end

        % Callback function
        function COMPAREButtonPushed(app, event)
         app.unsorted_array=[5, 8, 9, 41, 5, 0, 13, 6];
         
         if (app.INSERTIONSORTCheckBox.Value)
             insertion(app,app.unsorted_array)
         end
        if (app.BUBBLESORTCheckBox.Value)
     
             bubble_Sort(app,app.unsorted_array)
        end
        if  (app.COUNTINGSORTCheckBox.Value)
             countingSort(app,app.unsorted_array)
             
         end
        end

        % Callback function
        function CONNECTMEButtonPushed(app, event)
            web("beyzasayraci@gmail.com",'-browser')
        end

        % Button pushed function: DEFAULTDATAButton
        function DEFAULTDATAButtonPushed(app, event)
           
          try 
              global seed_array
              same = app.Switch.Value;
              if app.SEEDCheckBox.Value == 0
                  app.upper_bound.Value = randi([70,150],1,1);
                  app.lower_bound.Value = randi([0,25],1,1);
                  app.array_size.Value = randi([1,45],1,1);
                  if strcmp(same,'Not see same numbers')
                          app.unsorted_array = randperm(app.upper_bound.Value,app.array_size.Value);
                      
                  else
                      app.unsorted_array=randi([app.lower_bound.Value app.upper_bound.Value],1,app.array_size.Value);
                  end
                  bar(app.UIAxes2,app.unsorted_array,'FaceColor',"[0.4940 0.1840 0.5560]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
                  for i=1:numel(app.unsorted_array) 
                  title(app.UIAxes2,'Sort Algorithm Animation');
                  text(app.UIAxes2,(i),app.unsorted_array(i),num2str(app.unsorted_array(i),'%0.0f'),...
                               'HorizontalAlignment','center',...
                                'VerticalAlignment','bottom')
                  end
                  seed_array = app.unsorted_array;
              else
                 
                  bar(app.UIAxes2,seed_array,'FaceColor',"[0.4940 0.1840 0.5560]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
                  for i=1:numel(seed_array) 
                  title(app.UIAxes2,'Sort Algorithm Animation');
                  text(app.UIAxes2,(i),seed_array(i),num2str(seed_array(i),'%0.0f'),...
                               'HorizontalAlignment','center',...
                                'VerticalAlignment','bottom')
                  end
              end
          catch
              msgbox('Please, check it array', 'Error','error');
       
          end
        end

        % Callback function
        function Button_3Pushed(app, event)

            value = app.array_size.Value;
            app.array_size.Value = (value * 10) ;


        end

        % Callback function
        function Button_6Pushed(app, event)
            value = app.RANGEKnob.Value;
            app.ARRAYSZEKnob.Value = (value*10);
        end

        % Callback function
        function ImageClicked(app, event)
            web('https://www.ikcu.edu.tr/', '-browser') 
        end

        % Callback function
        function Image2Clicked(app, event)
             app.CallingApp = help; 
        end

        % Callback function
        function SEEDButtonPushed(app, event)
           
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            if app.STOPButton.Text == "STOP"
               app.STOPButton.Text = "CONTINUE";
               app.Lamp.Color = 'green';  
               uiwait(app.AlgorithmProjectUIFigure);
               app.Lamp.Color = 'red';  
               
            else
                app.STOPButton.Text="STOP";
                uiresume(app.AlgorithmProjectUIFigure);
            
                
            end
            bar(app.UIAxes2,app.unsorted_array,'FaceColor',"cyan",'EdgeColor',"black",'LineWidth',1.5) 
            for i=1:numel(app.unsorted_array) 
                  text(app.UIAxes2,(i),app.unsorted_array(i),num2str(app.unsorted_array(i),'%0.0f'),...
                      'HorizontalAlignment','center',...
                      'VerticalAlignment','bottom')
            end
        end

        % Button pushed function: CLEARButton_2
        function CLEARButton_2Pushed(app, event)
          cla(app.UIAxes2)
          app.array_size.Value = 0;
          app.lower_bound.Value = 0;
          app.upper_bound.Value = 0;
          app.EditField_11.Value = '';
          app.SEEDCheckBox.Value = 0;
          app.unsorted_array = [];
          
         
        end

        % Button pushed function: SKIPButton
        function SKIPButtonPushed(app, event)
             cla(app.UIAxes2)
             app.unsorted_array = sort(app.unsorted_array);
             hold(app.UIAxes2,"on")
             bar(app.UIAxes2,app.unsorted_array,'FaceColor',"[0.4940 0.1840 0.5560]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2)
             hold(app.UIAxes2,"off")
             for i=1:numel(app.unsorted_array) 
                 text(app.UIAxes2,(i),app.unsorted_array(i),num2str(app.unsorted_array(i),'%0.0f'),...
                        'HorizontalAlignment','center',...
                        'VerticalAlignment','bottom')
             end
               set(app.COUNTINGSORTButton,'Enable','on');
               set(app.BUCKETSORTButton,'Enable','on');
               set(app.RADIXSORTButton,'Enable','on');
               set(app.HEAPSORTButton,'Enable','on');
               set(app.QUICKSORTButton,'Enable','on');
               set(app.MERGESORTButton,'Enable','on');
               set(app.BUBBLESORTButton,'Enable','on');
               set(app.INSERTIONSORTButton,'Enable','on');
               set(app.RANDOMARRAYButton,'Enable','on');
               set(app.DEFAULTDATAButton,'Enable','on');
               set(app.SHELLSORTButton,'Enable','on');
               set(app.SELECTIONSORTButton,'Enable','on');
               set(app.CREATEARRAYButton_2,'Enable','on');
               set(app.TIMECOMPARISONButton,'Enable','on');
             while true 
                 pause(1.)
             end
             
        end

        % Image clicked function: Image4
        function Image4Clicked(app, event)
            [file_name,PathName] = uigetfile({'*.xls;*.xlsx;'},'Please select excel file');
            FileName=[PathName file_name];
            app.unsorted_array=xlsread(FileName,1,'A:A');
            bar(app.UIAxes2,app.unsorted_array,'FaceColor',"[0.4940 0.1840 0.5560]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
            for i=1:numel(app.unsorted_array)
                text(app.UIAxes2,(i),app.unsorted_array(i),num2str(app.unsorted_array(i),'%0.0f'),...
                'HorizontalAlignment','center',...
                'VerticalAlignment','bottom')
           end
        end

        % Image clicked function: Image5
        function Image5Clicked(app, event)
            answer = questdlg('Which file would you like to save the Array?','SAVE','Txt File','Xlsx File','Csv File','Csv File');
            switch answer
                case 'Txt File'
                    if isempty(app.unsorted_array)
                        msgbox('There is no created array...', 'Error','error');
                    else
                        [file,path] = uiputfile('.txt');
                        filename = fullfile(path,file);
                        if filename~=0                          % user pressed cancel
                           writematrix(app.unsorted_array,filename);
                        end
                    end
                case 'Xlsx File'
                    if isempty(app.unsorted_array)
                        msgbox('There is no created array...', 'Error','error');
                    else
                        [file,path] = uiputfile('.xlsx');
                        filename = fullfile(path,file);
                        if filename~=0                          % user pressed cancel
                          writematrix(app.unsorted_array,filename);
                        end
                    end
                case 'Csv File'
                    if isempty(app.unsorted_array)
                        msgbox('There is no created array...', 'Error','error');
                    else
                        [file,path] = uiputfile('*.csv');
                        filename = fullfile(path,file);
                        if filename~=0                              % user pressed cancel
                          writematrix(app.unsorted_array,filename);
                        end
                    end
            end
            
          
        end

        % Button pushed function: MERGESORTButton
        function MERGESORTButtonPushed(app, event)
            if isempty(app.unsorted_array)
                msgbox('Please set the array..','Error','error')
            else
                set(app.COUNTINGSORTButton,'Enable','off');
                set(app.BUCKETSORTButton,'Enable','off');
                set(app.RADIXSORTButton,'Enable','off');
                set(app.HEAPSORTButton,'Enable','off');
                set(app.QUICKSORTButton,'Enable','off');
                set(app.MERGESORTButton,'Enable','off');
                set(app.BUBBLESORTButton,'Enable','off');
                set(app.INSERTIONSORTButton,'Enable','off');
                set(app.RANDOMARRAYButton,'Enable','off');
                set(app.DEFAULTDATAButton,'Enable','off');
                set(app.SHELLSORTButton,'Enable','off');
                set(app.SELECTIONSORTButton,'Enable','off');
                set(app.CREATEARRAYButton_2,'Enable','off');
                set(app.TIMECOMPARISONButton,'Enable','off');
                Merge_Sort(app,app.unsorted_array,1,length(app.unsorted_array))
                set(app.COUNTINGSORTButton,'Enable','on');
                set(app.BUCKETSORTButton,'Enable','on');
                set(app.RADIXSORTButton,'Enable','on');
                set(app.HEAPSORTButton,'Enable','on');
                set(app.QUICKSORTButton,'Enable','on');
                set(app.MERGESORTButton,'Enable','on');
                set(app.BUBBLESORTButton,'Enable','on');
                set(app.INSERTIONSORTButton,'Enable','on');
                set(app.RANDOMARRAYButton,'Enable','on');
                set(app.DEFAULTDATAButton,'Enable','on');
                set(app.SHELLSORTButton,'Enable','on');
                set(app.SELECTIONSORTButton,'Enable','on');
                set(app.CREATEARRAYButton_2,'Enable','on');
                set(app.TIMECOMPARISONButton,'Enable','on');
            end
            String = 'The algorithm has been sorted successfully.';
            voice(app,String)
        end

        % Button pushed function: SELECTIONSORTButton
        function SELECTIONSORTButtonPushed(app, event)
            if isempty(app.unsorted_array)
                msgbox('Please set the array..','Error','error')
            else
                set(app.COUNTINGSORTButton,'Enable','off');
                set(app.BUCKETSORTButton,'Enable','off');
                set(app.RADIXSORTButton,'Enable','off');
                set(app.HEAPSORTButton,'Enable','off');
                set(app.QUICKSORTButton,'Enable','off');
                set(app.MERGESORTButton,'Enable','off');
                set(app.BUBBLESORTButton,'Enable','off');
                set(app.INSERTIONSORTButton,'Enable','off');
                set(app.RANDOMARRAYButton,'Enable','off');
                set(app.DEFAULTDATAButton,'Enable','off');
                set(app.SHELLSORTButton,'Enable','off');
                set(app.SELECTIONSORTButton,'Enable','off');
                set(app.CREATEARRAYButton_2,'Enable','off');
                set(app.TIMECOMPARISONButton,'Enable','off');
                Selection_Sort(app,app.unsorted_array)
                set(app.COUNTINGSORTButton,'Enable','on');
                set(app.BUCKETSORTButton,'Enable','on');
                set(app.RADIXSORTButton,'Enable','on');
                set(app.HEAPSORTButton,'Enable','on');
                set(app.QUICKSORTButton,'Enable','on');
                set(app.MERGESORTButton,'Enable','on');
                set(app.BUBBLESORTButton,'Enable','on');
                set(app.INSERTIONSORTButton,'Enable','on');
                set(app.RANDOMARRAYButton,'Enable','on');
                set(app.DEFAULTDATAButton,'Enable','on');
                set(app.SHELLSORTButton,'Enable','on');
                set(app.SELECTIONSORTButton,'Enable','on');
                set(app.CREATEARRAYButton_2,'Enable','on');
                set(app.TIMECOMPARISONButton,'Enable','on');
            end
            String = 'The algorithm has been sorted successfully.';
            voice(app,String)
        end

        % Button pushed function: SHELLSORTButton
        function SHELLSORTButtonPushed(app, event)
            if isempty(app.unsorted_array)
                msgbox('Please set the array..','Error','error')
            else
                set(app.COUNTINGSORTButton,'Enable','off');
                set(app.BUCKETSORTButton,'Enable','off');
                set(app.RADIXSORTButton,'Enable','off');
                set(app.HEAPSORTButton,'Enable','off');
                set(app.QUICKSORTButton,'Enable','off');
                set(app.MERGESORTButton,'Enable','off');
                set(app.BUBBLESORTButton,'Enable','off');
                set(app.INSERTIONSORTButton,'Enable','off');
                set(app.RANDOMARRAYButton,'Enable','off');
                set(app.DEFAULTDATAButton,'Enable','off');
                set(app.SHELLSORTButton,'Enable','off');
                set(app.SELECTIONSORTButton,'Enable','off');
                set(app.CREATEARRAYButton_2,'Enable','off');
                set(app.TIMECOMPARISONButton,'Enable','off');
                Shell_Sort(app,app.unsorted_array)
                set(app.COUNTINGSORTButton,'Enable','on');
                set(app.BUCKETSORTButton,'Enable','on');
                set(app.RADIXSORTButton,'Enable','on');
                set(app.HEAPSORTButton,'Enable','on');
                set(app.QUICKSORTButton,'Enable','on');
                set(app.MERGESORTButton,'Enable','on');
                set(app.BUBBLESORTButton,'Enable','on');
                set(app.INSERTIONSORTButton,'Enable','on');
                set(app.RANDOMARRAYButton,'Enable','on');
                set(app.DEFAULTDATAButton,'Enable','on');
                set(app.SHELLSORTButton,'Enable','on');
                set(app.SELECTIONSORTButton,'Enable','on');
                set(app.CREATEARRAYButton_2,'Enable','on');
                set(app.TIMECOMPARISONButton,'Enable','on');
            end
            
            String = 'The algorithm has been sorted successfully.';
            voice(app,String)
        end

        % Button pushed function: CREATEARRAYButton_2
        function CREATEARRAYButton_2Pushed(app, event)
                   
                 try
                   value = app.EditField_11.Value;
                   app.unsorted_array = [];
                   temp=erase(value,{'[',']'});
                   value=split(temp,{' ',','});
                 
                       app.unsorted_array=str2double(value);
                       bar(app.UIAxes2,app.unsorted_array,'FaceColor',"[0.4940 0.1840 0.5560]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
                       title(app.UIAxes2,'Sort Algorithm Animation');
                       for i=1:numel(app.unsorted_array)
                            text(app.UIAxes2,(i),app.unsorted_array(i),num2str(app.unsorted_array(i),'%0.0f'),...
                                              'HorizontalAlignment','center',...
                                              'VerticalAlignment','bottom')
                       end
                  catch
                      msgbox('Please, check it array', 'Error','error');
               
                   end


        end

        % Button pushed function: DEFAULTARRAYButton
        function DEFAULTARRAYButtonPushed(app, event)
           try 
                  %% create random values
                  app.Upper_Bound_13.Value = randi([70,150],1,1);
                  app.Lower_Bound_12.Value = randi([0,25],1,1);
                  app.Array_size_14.Value = randi([1,45],1,1);  
                  app.unsorted_array = randperm(app.Upper_Bound_13.Value,app.Array_size_14.Value); %%create random number for not see same numbers
                  app.TextArea_2.Value = num2str(app.unsorted_array); %%unsorted array
                  app.TextArea_3.Value = num2str(sort(app.unsorted_array)); %%sorted array

                  bar(app.UIAxes4,app.unsorted_array,'FaceColor',"black",'EdgeColor',"yellow",'LineWidth',2); %%create bar chart
                  for i=1:numel(app.unsorted_array) 
                  title(app.UIAxes4,'Unsorted Array');
                  text(app.UIAxes4,(i),app.unsorted_array(i),num2str(app.unsorted_array(i),'%0.0f'),...
                               'HorizontalAlignment','center',...
                                'VerticalAlignment','bottom')
                  end
                          
          catch
              msgbox('Please, check it array', 'Error','error');
       
          end
        end

        % Callback function
        function CREATEARRAYButton_3Pushed(app, event)
             try
                 value = app.TextArea_2.Value;
                 app.unsorted_array = []; %%create empty list
                 temp=erase(value,{'[',']'});
                 value=split(temp,{' ',','});
                 app.unsorted_array=str2double(value); %%manuel array
                 %app.TextArea_2.Value =  temp;
                 sorted_array = Insertion(app,app.unsorted_array);
                 app.TextArea_3.Value = num2str(sorted_array);
                 bar(app.UIAxes4,app.unsorted_array,'FaceColor',"black",'EdgeColor',"yellow",'LineWidth',2); %%create bar chart
                 for i=1:numel(app.unsorted_array) 
                 title(app.UIAxes4,'Unsorted Array');
                 text(app.UIAxes4,(i),app.unsorted_array(i),num2str(app.unsorted_array(i),'%0.0f'),...
                               'HorizontalAlignment','center',...
                                'VerticalAlignment','bottom')
                 end       
            catch
                msgbox("Please check your array.","Error","error")
            end
        end

        % Size changed function: BinarySearchTab
        function BinarySearchTabSizeChanged(app, event)
            position = app.BinarySearchTab.Position;
            
        end

        % Image clicked function: BinaryInfo
        function BinaryInfoClicked(app, event)
            inf_binary;
        end

        % Callback function
        function CreateArrayButton_2Pushed(app, event)
             try
                value = app.EditField_17.Value;
                app.unsorted_array = [];
                temp=erase(value,{'[',']'});
                value=split(temp,{' ',','});
                app.unsorted_array=str2double(value);
                app.TextArea_4.Value = num2str(app.unsorted_array);
                sorted_array = sort(app.unsorted_arrays);
                app.TextArea_6.Value = num2str(sorted_array);
                bar(app.UIAxes5,app.unsorted_array,'FaceColor',"black",'EdgeColor',"green",'LineWidth',2);
                for i=1:numel(app.unsorted_array) 
                title(app.UIAxes5,'Unsorted Array');
                text(app.UIAxes5,(i),app.unsorted_array(i),num2str(app.unsorted_array(i),'%0.0f'),...
                               'HorizontalAlignment','center',...
                                'VerticalAlignment','bottom')
                 end       
            catch
                msgbox("Please check your array.","Error","error")
            end
        end

        % Button pushed function: DefaultArrayButton
        function DefaultArrayButtonPushed(app, event)
            try
                  same = app.IdonotwantseesamenumbersCheckBox.Value;
                  app.upper_bound_6.Value = randi([70,150],1,1);
                  app.lower_bound_7.Value = randi([0,25],1,1);
                  app.array_size_16.Value = randi([1,45],1,1);
                  
                  if same  
                      app.unsorted_array = randperm(app.upper_bound_6.Value,app.array_size_16.Value); %%different random array and not same number
                      app.TextArea_4.Value = num2str(app.unsorted_array);
                      app.TextArea_6.Value = num2str(sort(app.unsorted_array));
    
                  else
                      app.unsorted_array=randi([app.lower_bound_7.Value app.upper_bound_6.Value],1,app.array_size_16.Value);%%different random array
                      app.TextArea_4.Value = num2str(app.unsorted_array);
                      app.TextArea_6.Value = num2str(sort(app.unsorted_array));
                          
                  end
                  bar(app.UIAxes5,app.unsorted_array,'FaceColor',"black",'EdgeColor',"green",'LineWidth',2);
                  for i=1:numel(app.unsorted_array) 
                  title(app.UIAxes5,'Unsorted Array');
                  text(app.UIAxes5,(i),app.unsorted_array(i),num2str(app.unsorted_array(i),'%0.0f'),...
                               'HorizontalAlignment','center',...
                                'VerticalAlignment','bottom')
                  end        
           catch
              msgbox('Please, check it array', 'Error','error');
       
           end
        end

        % Image clicked function: RandomizedInfo
        function RandomizedInfoClicked(app, event)
            inf_qs;
        end

        % Button down function: UIAxes7
        function UIAxes7ButtonDown(app, event)
            
        end

        % Image clicked function: FibonacciInfo
        function FibonacciInfoClicked(app, event)
            inf_fib;
        end

        % Callback function
        function Image8Clicked(app, event)
            web('www.linkedin.com/in/beyzasayraci','-browser')
        end

        % Image clicked function: MatrixInfo
        function MatrixInfoClicked(app, event)
            inf_matrix;
        end

        % Button pushed function: TIMECOMPARISONButton
        function TIMECOMPARISONButtonPushed(app, event)
            time;
        end

        % Callback function
        function Image7Clicked(app, event)
            app.CallingApp = my;
        end

        % Button pushed function: EnterMatrixByHandButton
        function EnterMatrixByHandButtonPushed(app, event)
            try
                global row
                global col
                global col2
                global matrix1
                global matrix2
                row = app.row.Value;
                col = app.col1androw2.Value;
                col2 = app.col2.Value;
                matrix1 = zeros(row,col);
                matrix2 = zeros(col,col2);
                app.UITable.Data = matrix1;
                app.UITable2.Data = matrix2;
                get(app.UITable,"Data");
                get(app.UITable2,"Data");
                app.UITable3.Data(:) = [];
            catch
                msgbox('Please check your matrix','Error','error')
            end
        end

        % Image clicked function: SortingInfo
        function SortingInfoClicked(app, event)
            app.CallingApp = inf_sorting;
        end

        % Size changed function: SortAlgorithmsTab
        function SortAlgorithmsTabSizeChanged(app, event)
            position = app.SortAlgorithmsTab.Position;
            
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
            
        end

        % Callback function
        function Image11Clicked(app, event)
            web('https://www.youtube.com/channel/UCVx9odXaosySGaDtXX96QGA','-browser')
        end

        % Image clicked function: Image12
        function Image12Clicked(app, event)
            global matrix1
            matrix1 = [];
%             cla(app.UITable)
            [FileNameBodeWrite, PathNameBoderWrite] = uigetfile({'*.xlsx'; '*.csv'}, 'Please select file..');
            file_name = [PathNameBoderWrite,FileNameBodeWrite];
            app.UITable.Data = xlsread(file_name);
        end

        % Image clicked function: Image12_2
        function Image12_2Clicked(app, event)
            global matrix2
            matrix2 = [];
%             cla(app.UITable2)
            [FileNameBodeWrite, PathNameBoderWrite] = uigetfile({'*.xlsx'; '*.csv'}, 'Please select file..');
            file_name = [PathNameBoderWrite,FileNameBodeWrite];
            app.UITable2.Data = xlsread(file_name);
        end

        % Image clicked function: Image12_3
        function Image12_3Clicked(app, event)
            global matrix3
            if length(matrix3) == 0
                msgbox('Please check your matrix','Error','error')
            else
                [FileNameBodeWrite, PathNameBoderWrite] = uiputfile({'*.xlsx'; '*.csv'}, 'Save File..',['defname' '.xlsx']);
                if FileNameBodeWrite ~= 0
                    if exist([PathNameBoderWrite FileNameBodeWrite],'file')
                        delete([PathNameBoderWrite FileNameBodeWrite]);
                    end
                    xlswrite([PathNameBoderWrite FileNameBodeWrite],app.UITable3.Data,1,'A2');
                end
            end
        end

        % Image clicked function: Image13
        function Image13Clicked(app, event)
            global row
            global col
            global col2
            global row2
            global matrix1
            global matrix2
            global matrix3
            matrix1 = app.UITable.Data;
            matrix2 = app.UITable2.Data;
            if isempty(matrix1) && isempty(matrix2)
                 msgbox('Please check your matrix','Error','error')
                 app.UITable3.Data(:) = []
            else
                if col ~= col2 || row ~= row2
                msgbox('Please check your matrix..','Error','error')
                app.UITable3.Data(:) = []
                else
                    [row , col] = size(matrix1); %%matrix1 size determination
                    [row2, col2] = size(matrix2); %%matrix2 size determination
                    matrix3 = zeros(row,col2);
                    matrix3 = matrix1 + matrix2; %%Create the result matrix
                     
                 end
                    app.UITable3.Data = matrix3; 
     
            end
        end

        % Image clicked function: Image14
        function Image14Clicked(app, event)
            global row
            global col
            global col2
            global row2
            global matrix1
            global matrix2
            global matrix3
            matrix1 = app.UITable.Data;
            matrix2 = app.UITable2.Data;
            if isempty(matrix1) && isempty(matrix2)
                 msgbox('Please check your matrix','Error','error')
                 app.UITable3.Data(:) = [];
            else
                if col ~= col2 || row ~= row2
                msgbox('Please check your matrix..','Error','error')
                app.UITable3.Data(:) = [];
                else
                    [row , col] = size(matrix1); %%matrix1 size determination
                    [row2, col2] = size(matrix2); %%matrix2 size determination
                    matrix3 = zeros(row,col2);
                    matrix3 = matrix1 - matrix2; %%Create the result matrix
                     
                 end
                    app.UITable3.Data = matrix3; 
            end
     
        end

        % Image clicked function: IkcLogo
        function IkcLogoImageClicked(app, event)
            web('https://www.ikcu.edu.tr/', '-browser')
        end

        % Image clicked function: Youtube
        function YoutubeImageClicked(app, event)
            web('https://www.youtube.com/channel/UCVx9odXaosySGaDtXX96QGA', '-browser')
        end

        % Image clicked function: FeedBack
        function FeedBackImageClicked(app, event)
            web mailto:murat_kilci@hotmail.com?subject='About the introductory algorithms project.'
        end

        % Image clicked function: Instagram
        function InstagramImageClicked(app, event)
            web('https://www.instagram.com/murat_kilci/?igshid=YmMyMTA2M2Y%3D', '-browser')
        end

        % Image clicked function: LinkedIn
        function LinkedInImageClicked(app, event)
            web('https://www.linkedin.com/in/murat-kilci-4615961b9/', '-browser')
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            ps = app.PasswordEditField.Value;
            un=app.UserNameEditField.Value;
            preUN = ['muratkilci']
            prePS= [num2str(1245)];%Presset password
            %% Compare the enterd password with this preset password
            if strcmp(ps,prePS) && strcmp(un,preUN)
                app.TabGroup2.SelectedTab = app.TabGroup2.Children(2);
            else
                msgbox('Invalid user name or password.')
            end
        end

        % Image clicked function: LogOut
        function LogOutImageClicked(app, event)
            button = questdlg('Do you want to log out?','Continue Operation','Yes','No','No');
            if strcmp(button,'Yes')
                app.TabGroup2.SelectedTab = app.TabGroup2.Children(1);
            elseif strcmp(button,'No')               
            
            end
        end

        % Image clicked function: LogOut1
        function LogOut1ImageClicked(app, event)
            button = questdlg('Do you want to log out?','Continue Operation','Yes','No','No');
            if strcmp(button,'Yes')
                app.TabGroup2.SelectedTab = app.TabGroup2.Children(1);
            elseif strcmp(button,'No')               
            
            end
        end

        % Image clicked function: LogOut2
        function LogOut2ImageClicked(app, event)
            button = questdlg('Do you want to log out?','Continue Operation','Yes','No','No');
            if strcmp(button,'Yes')
                app.TabGroup2.SelectedTab = app.TabGroup2.Children(1);
            elseif strcmp(button,'No')               
            
            end
        end

        % Image clicked function: LogOut3
        function LogOut3ImageClicked(app, event)
            button = questdlg('Do you want to log out?','Continue Operation','Yes','No','No');
            if strcmp(button,'Yes')
                app.TabGroup2.SelectedTab = app.TabGroup2.Children(1);
            elseif strcmp(button,'No')               
            
            end
        end

        % Image clicked function: LogOut4
        function LogOut4ImageClicked(app, event)
            button = questdlg('Do you want to log out?','Continue Operation','Yes','No','No');
            if strcmp(button,'Yes')
                app.TabGroup2.SelectedTab = app.TabGroup2.Children(1);
            elseif strcmp(button,'No')               
            
            end
        end

        % Button pushed function: AnimationButton
        function AnimationButtonPushed(app, event)
            
points = 2000;
x = zeros(points,1)+0.0001;
y = zeros(points,1)+0.0001;
sz = zeros(points,1)';

Golden_Angle = (1 - sqrt(5))/2;
protospacer = 0.01;
protoangel = 0;

fig = figure;
fig.Position = [300,50,650,650];
fig.Color = 'w';
ax = axes(fig,'Position',[0 0 1 1]);
h = scatter(ax,zeros(1,1),zeros(1,1),1,1,'filled','MarkerEdgeColor','k');
colormap(ax, 'hsv')
ax.XLim = [-30 30];
ax.YLim = [-30 30];
ax.YColor = 'w';
ax.XColor = 'w';
for i = 1:points
    if i==600
        colormap(ax, 'prism')
    elseif i==1200
        colormap(ax, 'colorcube')
    end
    x(i) = sin(protoangel) * protospacer;
    y(i) = cos(protoangel) * protospacer;
    protospacer = protospacer + 0.02;
    protoangel = protoangel + (2*pi())/Golden_Angle;
    sz(i) = 0.25*i+10;
    h.XData = x(1:i);
    h.YData = y(1:i);
    h.CData = sz(1:i);
    h.SizeData = sz(1:i);
    pause(0)
end
        end

        % Image clicked function: Image15
        function Image15Clicked(app, event)
            closereq();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AlgorithmProjectUIFigure = uifigure('Visible', 'off');
            app.AlgorithmProjectUIFigure.Name = 'Algorithm Project';
            app.TabGroup2 = uitabgroup(app.AlgorithmProjectUIFigure);
            app.LoginTab = uitab(app.TabGroup2);
            app.LoginTab.Title = 'Login';
            app.Backgorund = uiimage(app.LoginTab);
            app.Backgorund.ImageSource = 'gradient-background-backdrop-multicolor-colorful-vibrant-3840x2160-6723.png';
            app.IkcLogo = uiimage(app.LoginTab);
            app.IkcLogo.ImageClickedFcn = createCallbackFcn(app, @IkcLogoImageClicked, true);
            app.IkcLogo.ImageSource = 'ikclogo.png';
            app.GifImage = uiimage(app.LoginTab);
            app.GifImage.ImageSource = 'messagif.gif';
            app.Youtube = uiimage(app.LoginTab);
            app.Youtube.ImageClickedFcn = createCallbackFcn(app, @YoutubeImageClicked, true);
            app.Youtube.ImageSource = 'icon-youtube.png';
            app.FeedBack = uiimage(app.LoginTab);
            app.FeedBack.ImageClickedFcn = createCallbackFcn(app, @FeedBackImageClicked, true);
            app.FeedBack.ImageSource = 'icons8-email-48.png';
            app.Instagram = uiimage(app.LoginTab);
            app.Instagram.ImageClickedFcn = createCallbackFcn(app, @InstagramImageClicked, true);
            app.Instagram.ImageSource = 'icon-instagram.png';
            app.LinkedIn = uiimage(app.LoginTab);
            app.LinkedIn.ImageClickedFcn = createCallbackFcn(app, @LinkedInImageClicked, true);
            app.LinkedIn.ImageSource = 'indir (4).png';
            app.LoginPanel = uipanel(app.LoginTab);
            app.LoginPanel.Title = 'Login';
            app.UserNameEditFieldLabel = uilabel(app.LoginPanel);
            app.UserNameEditFieldLabel.Text = {'User Name'; ''};
            app.UserNameEditField = uieditfield(app.LoginPanel, 'text');
            app.PasswordEditFieldLabel = uilabel(app.LoginPanel);
            app.PasswordEditFieldLabel.Text = {'Password'; ''};
            app.PasswordEditField = uieditfield(app.LoginPanel, 'text');
            app.SubmitButton = uibutton(app.LoginPanel, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.Image15 = uiimage(app.LoginTab);
            app.Image15.ImageClickedFcn = createCallbackFcn(app, @Image15Clicked, true);
            app.Image15.ImageSource = 'icons8-log-out-32.png';
            app.AppTab = uitab(app.TabGroup2);
            app.AppTab.Title = 'App';
            app.TabGroup = uitabgroup(app.AppTab);
            app.SortAlgorithmsTab = uitab(app.TabGroup);
            app.SortAlgorithmsTab.SizeChangedFcn = createCallbackFcn(app, @SortAlgorithmsTabSizeChanged, true);
            app.SortAlgorithmsTab.Title = 'Sort Algorithms';
            app.UIAxes2 = uiaxes(app.SortAlgorithmsTab);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.array_size = uieditfield(app.SortAlgorithmsTab, 'numeric');
            app.array_size.Limits = [0 Inf];
            app.lower_bound = uieditfield(app.SortAlgorithmsTab, 'numeric');
            app.INSERTIONSORTButton = uibutton(app.SortAlgorithmsTab, 'push');
            app.INSERTIONSORTButton.ButtonPushedFcn = createCallbackFcn(app, @INSERTIONSORTButtonPushed, true);
            app.INSERTIONSORTButton.Text = 'INSERTION SORT';
            app.BUBBLESORTButton = uibutton(app.SortAlgorithmsTab, 'push');
            app.BUBBLESORTButton.ButtonPushedFcn = createCallbackFcn(app, @BUBBLESORTButtonPushed, true);
            app.BUBBLESORTButton.Text = 'BUBBLE SORT';
            app.MERGESORTButton = uibutton(app.SortAlgorithmsTab, 'push');
            app.MERGESORTButton.ButtonPushedFcn = createCallbackFcn(app, @MERGESORTButtonPushed, true);
            app.MERGESORTButton.Text = 'MERGE SORT';
            app.QUICKSORTButton = uibutton(app.SortAlgorithmsTab, 'push');
            app.QUICKSORTButton.ButtonPushedFcn = createCallbackFcn(app, @QUICKSORTButtonPushed, true);
            app.QUICKSORTButton.Text = 'QUICK SORT';
            app.HEAPSORTButton = uibutton(app.SortAlgorithmsTab, 'push');
            app.HEAPSORTButton.ButtonPushedFcn = createCallbackFcn(app, @HEAPSORTButtonPushed, true);
            app.HEAPSORTButton.Text = 'HEAP SORT';
            app.RADIXSORTButton = uibutton(app.SortAlgorithmsTab, 'push');
            app.RADIXSORTButton.ButtonPushedFcn = createCallbackFcn(app, @RADIXSORTButtonPushed, true);
            app.RADIXSORTButton.Text = 'RADIX SORT';
            app.COUNTINGSORTButton = uibutton(app.SortAlgorithmsTab, 'push');
            app.COUNTINGSORTButton.ButtonPushedFcn = createCallbackFcn(app, @COUNTINGSORTButtonPushed, true);
            app.COUNTINGSORTButton.Text = 'COUNTING SORT';
            app.BUCKETSORTButton = uibutton(app.SortAlgorithmsTab, 'push');
            app.BUCKETSORTButton.ButtonPushedFcn = createCallbackFcn(app, @BUCKETSORTButtonPushed, true);
            app.BUCKETSORTButton.Text = 'BUCKET SORT';
            app.RANDOMARRAYButton = uibutton(app.SortAlgorithmsTab, 'push');
            app.RANDOMARRAYButton.ButtonPushedFcn = createCallbackFcn(app, @RANDOMARRAYButtonPushed, true);
            app.RANDOMARRAYButton.Text = 'RANDOM ARRAY';
            app.SPEEDSliderLabel = uilabel(app.SortAlgorithmsTab);
            app.SPEEDSliderLabel.Text = 'SPEED';
            app.SPEEDSlider = uislider(app.SortAlgorithmsTab);
            app.SPEEDSlider.Limits = [0 10];
            app.SPEEDSlider.MajorTicks = [];
            app.DEFAULTDATAButton = uibutton(app.SortAlgorithmsTab, 'push');
            app.DEFAULTDATAButton.ButtonPushedFcn = createCallbackFcn(app, @DEFAULTDATAButtonPushed, true);
            app.DEFAULTDATAButton.Text = 'DEFAULT DATA';
            app.SHELLSORTButton = uibutton(app.SortAlgorithmsTab, 'push');
            app.SHELLSORTButton.ButtonPushedFcn = createCallbackFcn(app, @SHELLSORTButtonPushed, true);
            app.SHELLSORTButton.Text = 'SHELL SORT';
            app.SELECTIONSORTButton = uibutton(app.SortAlgorithmsTab, 'push');
            app.SELECTIONSORTButton.ButtonPushedFcn = createCallbackFcn(app, @SELECTIONSORTButtonPushed, true);
            app.SELECTIONSORTButton.Text = 'SELECTION SORT';
            app.SEEDCheckBox = uicheckbox(app.SortAlgorithmsTab);
            app.SEEDCheckBox.Text = 'SEED';
            app.LOWERBOUNDLabel = uilabel(app.SortAlgorithmsTab);
            app.LOWERBOUNDLabel.Text = 'LOWER BOUND';
            app.UPPERBOUNDLabel = uilabel(app.SortAlgorithmsTab);
            app.UPPERBOUNDLabel.Text = 'UPPER BOUND';
            app.upper_bound = uieditfield(app.SortAlgorithmsTab, 'numeric');
            app.ARRAYSIZELabel = uilabel(app.SortAlgorithmsTab);
            app.ARRAYSIZELabel.Text = 'ARRAY SIZE';
            app.STOPButton = uibutton(app.SortAlgorithmsTab, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.CLEARButton_2 = uibutton(app.SortAlgorithmsTab, 'push');
            app.CLEARButton_2.ButtonPushedFcn = createCallbackFcn(app, @CLEARButton_2Pushed, true);
            app.CLEARButton_2.Text = 'CLEAR';
            app.SKIPButton = uibutton(app.SortAlgorithmsTab, 'push');
            app.SKIPButton.ButtonPushedFcn = createCallbackFcn(app, @SKIPButtonPushed, true);
            app.SKIPButton.Text = 'SKIP';
            app.Switch = uiswitch(app.SortAlgorithmsTab, 'toggle');
            app.Switch.Items = {'Not see same numbers', 'See same numbers'};
            app.Switch.Value = 'Not see same numbers';
            app.Image4 = uiimage(app.SortAlgorithmsTab);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image4Clicked, true);
            app.Image4.ImageSource = 'Excel-icon.png';
            app.Image5 = uiimage(app.SortAlgorithmsTab);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Image5Clicked, true);
            app.Image5.ImageSource = 'indir (1).png';
            app.Lamp = uilamp(app.SortAlgorithmsTab);
            app.EditField_11 = uieditfield(app.SortAlgorithmsTab, 'text');
            app.CREATEARRAYButton_2 = uibutton(app.SortAlgorithmsTab, 'push');
            app.CREATEARRAYButton_2.ButtonPushedFcn = createCallbackFcn(app, @CREATEARRAYButton_2Pushed, true);
            app.CREATEARRAYButton_2.Text = 'CREATE ARRAY';
            app.PleasesetyourmanuelarrayLabel = uilabel(app.SortAlgorithmsTab);
            app.PleasesetyourmanuelarrayLabel.Text = 'Please set your manuel array ';
            app.TIMECOMPARISONButton = uibutton(app.SortAlgorithmsTab, 'push');
            app.TIMECOMPARISONButton.ButtonPushedFcn = createCallbackFcn(app, @TIMECOMPARISONButtonPushed, true);
            app.TIMECOMPARISONButton.Text = 'TIME COMPARISON';
            app.SortingInfo = uiimage(app.SortAlgorithmsTab);
            app.SortingInfo.ImageClickedFcn = createCallbackFcn(app, @SortingInfoClicked, true);
            app.SortingInfo.ImageSource = 'icons8-information-64.png';
            app.LogOut = uiimage(app.SortAlgorithmsTab);
            app.LogOut.ImageClickedFcn = createCallbackFcn(app, @LogOutImageClicked, true);
            app.LogOut.ImageSource = 'icons8-log-out-64.png';
            app.ReadLabel = uilabel(app.SortAlgorithmsTab);
            app.ReadLabel.Text = 'Read ';
            app.WriteLabel = uilabel(app.SortAlgorithmsTab);
            app.WriteLabel.Text = 'Write ';
            app.MatrixOperationsTab = uitab(app.TabGroup);
            app.MatrixOperationsTab.Title = 'Matrix Operations';
            app.UITable = uitable(app.MatrixOperationsTab);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.UITable2 = uitable(app.MatrixOperationsTab);
            app.UITable2.ColumnName = {''};
            app.UITable2.RowName = {};
            app.UITable2.ColumnSortable = true;
            app.UITable2.ColumnEditable = true;
            app.UITable3 = uitable(app.MatrixOperationsTab);
            app.UITable3.ColumnName = {''};
            app.UITable3.RowName = {};
            app.ClearButton = uibutton(app.MatrixOperationsTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.Button = uibutton(app.MatrixOperationsTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'icons8-multiply-64.png';
            app.Button.Text = '';
            app.FIRSTMATRIXLabel = uilabel(app.MatrixOperationsTab);
            app.FIRSTMATRIXLabel.Text = 'FIRST MATRIX ';
            app.SECONDMATRIXLabel = uilabel(app.MatrixOperationsTab);
            app.SECONDMATRIXLabel.Text = 'SECOND MATRIX ';
            app.CreateButton = uibutton(app.MatrixOperationsTab, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create ';
            app.RESULTMATRIXLabel = uilabel(app.MatrixOperationsTab);
            app.RESULTMATRIXLabel.Text = 'RESULT MATRIX ';
            app.MatrixOperationsLabel = uilabel(app.MatrixOperationsTab);
            app.MatrixOperationsLabel.Text = 'Matrix Operations';
            app.FirstMatrixRowLabel = uilabel(app.MatrixOperationsTab);
            app.FirstMatrixRowLabel.Text = 'First Matrix  Row ';
            app.row = uieditfield(app.MatrixOperationsTab, 'numeric');
            app.row.Limits = [1 Inf];
            app.row.Value = 1;
            app.FirstMatrixColumnLabel = uilabel(app.MatrixOperationsTab);
            app.FirstMatrixColumnLabel.Text = {'First Matrix Column  '; ''; ''; ''; ' '};
            app.col1androw2 = uieditfield(app.MatrixOperationsTab, 'numeric');
            app.col1androw2.Limits = [1 Inf];
            app.col1androw2.Value = 1;
            app.SecondMatrixColumnLabel = uilabel(app.MatrixOperationsTab);
            app.SecondMatrixColumnLabel.Text = 'Second Matrix Column ';
            app.col2 = uieditfield(app.MatrixOperationsTab, 'numeric');
            app.col2.Limits = [1 Inf];
            app.col2.Value = 1;
            app.MatrixInfo = uiimage(app.MatrixOperationsTab);
            app.MatrixInfo.ImageClickedFcn = createCallbackFcn(app, @MatrixInfoClicked, true);
            app.MatrixInfo.ImageSource = 'icons8-information-64.png';
            app.EnterMatrixByHandButton = uibutton(app.MatrixOperationsTab, 'push');
            app.EnterMatrixByHandButton.ButtonPushedFcn = createCallbackFcn(app, @EnterMatrixByHandButtonPushed, true);
            app.EnterMatrixByHandButton.Text = 'Enter Matrix By Hand';
            app.Image12 = uiimage(app.MatrixOperationsTab);
            app.Image12.ImageClickedFcn = createCallbackFcn(app, @Image12Clicked, true);
            app.Image12.ImageSource = 'Excel-icon.png';
            app.Image12_2 = uiimage(app.MatrixOperationsTab);
            app.Image12_2.ImageClickedFcn = createCallbackFcn(app, @Image12_2Clicked, true);
            app.Image12_2.ImageSource = 'Excel-icon.png';
            app.Image12_3 = uiimage(app.MatrixOperationsTab);
            app.Image12_3.ImageClickedFcn = createCallbackFcn(app, @Image12_3Clicked, true);
            app.Image12_3.ImageSource = 'unnamed.png';
            app.SecondMatrixRowEditFieldLabel = uilabel(app.MatrixOperationsTab);
            app.SecondMatrixRowEditFieldLabel.Text = 'Second Matrix Row    ';
            app.SecondMatrixRowEditField = uieditfield(app.MatrixOperationsTab, 'numeric');
            app.SecondMatrixRowEditField.Limits = [1 Inf];
            app.SecondMatrixRowEditField.Value = 1;
            app.Image13 = uiimage(app.MatrixOperationsTab);
            app.Image13.ImageClickedFcn = createCallbackFcn(app, @Image13Clicked, true);
            app.Image13.ImageSource = 'icons8-plus-math-64.png';
            app.Image14 = uiimage(app.MatrixOperationsTab);
            app.Image14.ImageClickedFcn = createCallbackFcn(app, @Image14Clicked, true);
            app.Image14.ImageSource = 'icons8-minus-64.png';
            app.LogOut1 = uiimage(app.MatrixOperationsTab);
            app.LogOut1.ImageClickedFcn = createCallbackFcn(app, @LogOut1ImageClicked, true);
            app.LogOut1.ImageSource = 'icons8-log-out-64.png';
            app.FibonacciTab = uitab(app.TabGroup);
            app.FibonacciTab.Title = 'Fibonacci ';
            app.UIAxes = uiaxes(app.FibonacciTab);
            xlabel(app.UIAxes, 'Value')
            ylabel(app.UIAxes, 'Golden Ratio')
            app.UIAxes7 = uiaxes(app.FibonacciTab);
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes7.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes7.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes7.ButtonDownFcn = createCallbackFcn(app, @UIAxes7ButtonDown, true);
            app.EditField = uieditfield(app.FibonacciTab, 'numeric');
            app.EditField.Limits = [1 Inf];
            app.EditField.Value = 1;
            app.CALCULATEButton = uibutton(app.FibonacciTab, 'push');
            app.CALCULATEButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULATEButtonPushed, true);
            app.CALCULATEButton.Text = 'CALCULATE';
            app.NthnumberofFibonacciLabel = uilabel(app.FibonacciTab);
            app.NthnumberofFibonacciLabel.Text = {'N''th number of '; 'Fibonacci'};
            app.TextArea = uitextarea(app.FibonacciTab);
            app.FibonacciSeriesLabel = uilabel(app.FibonacciTab);
            app.FibonacciSeriesLabel.Text = '          Fibonacci Series';
            app.SeethefibonacciratioButton = uibutton(app.FibonacciTab, 'push');
            app.SeethefibonacciratioButton.ButtonPushedFcn = createCallbackFcn(app, @SeethefibonacciratioButtonPushed, true);
            app.SeethefibonacciratioButton.Text = 'See the fibonacci ratio';
            app.ClearButton_4 = uibutton(app.FibonacciTab, 'push');
            app.ClearButton_4.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_4Pushed, true);
            app.ClearButton_4.Text = 'Clear';
            app.FIBONACCILabel = uilabel(app.FibonacciTab);
            app.FIBONACCILabel.Text = 'FIBONACCI';
            app.FibonacciInfo = uiimage(app.FibonacciTab);
            app.FibonacciInfo.ImageClickedFcn = createCallbackFcn(app, @FibonacciInfoClicked, true);
            app.FibonacciInfo.ImageSource = 'icons8-information-64.png';
            app.fibonacci_answer = uieditfield(app.FibonacciTab, 'text');
            app.LogOut2 = uiimage(app.FibonacciTab);
            app.LogOut2.ImageClickedFcn = createCallbackFcn(app, @LogOut2ImageClicked, true);
            app.LogOut2.ImageSource = 'icons8-log-out-64.png';
            app.AnimationButton = uibutton(app.FibonacciTab, 'push');
            app.AnimationButton.ButtonPushedFcn = createCallbackFcn(app, @AnimationButtonPushed, true);
            app.AnimationButton.Text = 'Colorful Fibonacci Scatter Spiral';
            app.BinarySearchTab = uitab(app.TabGroup);
            app.BinarySearchTab.SizeChangedFcn = createCallbackFcn(app, @BinarySearchTabSizeChanged, true);
            app.BinarySearchTab.Title = 'Binary Search';
            app.UIAxes_2 = uiaxes(app.BinarySearchTab);
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.UIAxes4 = uiaxes(app.BinarySearchTab);
            app.RANDOMARRAYButton_2 = uibutton(app.BinarySearchTab, 'push');
            app.RANDOMARRAYButton_2.ButtonPushedFcn = createCallbackFcn(app, @RANDOMARRAYButton_2Pushed, true);
            app.RANDOMARRAYButton_2.Text = 'RANDOM ARRAY';
            app.SEARCHNUMBERINDEXButton = uibutton(app.BinarySearchTab, 'push');
            app.SEARCHNUMBERINDEXButton.ButtonPushedFcn = createCallbackFcn(app, @SEARCHNUMBERINDEXButtonPushed, true);
            app.SEARCHNUMBERINDEXButton.Text = 'SEARCH NUMBER INDEX';
            app.Spinner = uispinner(app.BinarySearchTab);
            app.EditField_3 = uieditfield(app.BinarySearchTab, 'numeric');
            app.EditField_3.Editable = 'off';
            app.TextArea_2 = uitextarea(app.BinarySearchTab);
            app.TextArea_3 = uitextarea(app.BinarySearchTab);
            app.UnsortedArrayLabel = uilabel(app.BinarySearchTab);
            app.UnsortedArrayLabel.Text = '   Unsorted Array';
            app.SortedArrayLabel = uilabel(app.BinarySearchTab);
            app.SortedArrayLabel.Text = '     Sorted Array';
            app.BINARYSEARCHLabel = uilabel(app.BinarySearchTab);
            app.BINARYSEARCHLabel.Text = '  BINARY SEARCH';
            app.CLEARButton = uibutton(app.BinarySearchTab, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.BINARYSEARCHLabel_2 = uilabel(app.BinarySearchTab);
            app.BINARYSEARCHLabel_2.Text = 'BINARY SEARCH';
            app.Lower_Bound_12 = uieditfield(app.BinarySearchTab, 'numeric');
            app.Upper_Bound_13 = uieditfield(app.BinarySearchTab, 'numeric');
            app.Array_size_14 = uieditfield(app.BinarySearchTab, 'numeric');
            app.ARRAYSIZELabel_2 = uilabel(app.BinarySearchTab);
            app.ARRAYSIZELabel_2.Text = 'ARRAY SIZE';
            app.UPPERBOUNDLabel_2 = uilabel(app.BinarySearchTab);
            app.UPPERBOUNDLabel_2.Text = 'UPPER BOUND';
            app.LOWERBOUNDLabel_2 = uilabel(app.BinarySearchTab);
            app.LOWERBOUNDLabel_2.Text = 'LOWER BOUND';
            app.DEFAULTARRAYButton = uibutton(app.BinarySearchTab, 'push');
            app.DEFAULTARRAYButton.ButtonPushedFcn = createCallbackFcn(app, @DEFAULTARRAYButtonPushed, true);
            app.DEFAULTARRAYButton.Text = 'DEFAULT ARRAY';
            app.BinaryInfo = uiimage(app.BinarySearchTab);
            app.BinaryInfo.ImageClickedFcn = createCallbackFcn(app, @BinaryInfoClicked, true);
            app.BinaryInfo.ImageSource = 'icons8-information-64.png';
            app.LogOut3 = uiimage(app.BinarySearchTab);
            app.LogOut3.ImageClickedFcn = createCallbackFcn(app, @LogOut3ImageClicked, true);
            app.LogOut3.ImageSource = 'icons8-log-out-64.png';
            app.RandomizedSelectTab = uitab(app.TabGroup);
            app.RandomizedSelectTab.Title = 'Randomized Select';
            app.UIAxes6 = uiaxes(app.RandomizedSelectTab);
            app.UIAxes5 = uiaxes(app.RandomizedSelectTab);
            app.TextArea_4 = uitextarea(app.RandomizedSelectTab);
            app.TextArea_4.Editable = 'off';
            app.RandomArrayButton = uibutton(app.RandomizedSelectTab, 'push');
            app.RandomArrayButton.ButtonPushedFcn = createCallbackFcn(app, @RandomArrayButtonPushed, true);
            app.RandomArrayButton.Text = 'Random Array';
            app.ithsmallestButton = uibutton(app.RandomizedSelectTab, 'push');
            app.ithsmallestButton.ButtonPushedFcn = createCallbackFcn(app, @ithsmallestButtonPushed, true);
            app.ithsmallestButton.Text = 'ith smallest';
            app.TextArea_5 = uitextarea(app.RandomizedSelectTab);
            app.TextArea_5.Editable = 'off';
            app.upper_bound_6 = uieditfield(app.RandomizedSelectTab, 'numeric');
            app.lower_bound_7 = uieditfield(app.RandomizedSelectTab, 'numeric');
            app.EditField_8 = uieditfield(app.RandomizedSelectTab, 'numeric');
            app.EditField_8.Value = 1;
            app.ClearButton_2 = uibutton(app.RandomizedSelectTab, 'push');
            app.ClearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_2Pushed, true);
            app.ClearButton_2.Text = 'Clear';
            app.UNSORTEDARRAYLabel = uilabel(app.RandomizedSelectTab);
            app.UNSORTEDARRAYLabel.Text = 'UNSORTED ARRAY';
            app.INDEXLabel = uilabel(app.RandomizedSelectTab);
            app.INDEXLabel.Text = 'INDEX';
            app.NUMBERLabel = uilabel(app.RandomizedSelectTab);
            app.NUMBERLabel.Text = 'NUMBER';
            app.RANDOMIZEDSELECTLabel = uilabel(app.RandomizedSelectTab);
            app.RANDOMIZEDSELECTLabel.Text = 'RANDOMIZED SELECT';
            app.TextArea_6 = uitextarea(app.RandomizedSelectTab);
            app.TextArea_6.Editable = 'off';
            app.SORTEDARRAYLabel = uilabel(app.RandomizedSelectTab);
            app.SORTEDARRAYLabel.Text = 'SORTED ARRAY';
            app.array_size_16 = uieditfield(app.RandomizedSelectTab, 'numeric');
            app.ARRAYSIZELabel_3 = uilabel(app.RandomizedSelectTab);
            app.ARRAYSIZELabel_3.Text = 'ARRAY SIZE';
            app.UPPERBOUNDLabel_3 = uilabel(app.RandomizedSelectTab);
            app.UPPERBOUNDLabel_3.Text = 'UPPER BOUND';
            app.LOWERBOUNDLabel_3 = uilabel(app.RandomizedSelectTab);
            app.LOWERBOUNDLabel_3.Text = 'LOWER BOUND';
            app.DefaultArrayButton = uibutton(app.RandomizedSelectTab, 'push');
            app.DefaultArrayButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultArrayButtonPushed, true);
            app.DefaultArrayButton.Text = 'Default Array';
            app.IdonotwantseesamenumbersCheckBox = uicheckbox(app.RandomizedSelectTab);
            app.IdonotwantseesamenumbersCheckBox.Text = 'I do not want see same numbers';
            app.RandomizedInfo = uiimage(app.RandomizedSelectTab);
            app.RandomizedInfo.ImageClickedFcn = createCallbackFcn(app, @RandomizedInfoClicked, true);
            app.RandomizedInfo.ImageSource = 'icons8-information-64.png';
            app.LogOut4 = uiimage(app.RandomizedSelectTab);
            app.LogOut4.ImageClickedFcn = createCallbackFcn(app, @LogOut4ImageClicked, true);
            app.LogOut4.ImageSource = 'icons8-log-out-64.png';
            % Show the figure after all components are created
            app.AlgorithmProjectUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matlab_project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AlgorithmProjectUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AlgorithmProjectUIFigure)
        end
    end
end